classdef CESTsimu_v071 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup3                     matlab.ui.container.TabGroup
        BasicTab                      matlab.ui.container.Tab
        RecoverytimeEditField         matlab.ui.control.NumericEditField
        RecoverytimeEditFieldLabel    matlab.ui.control.Label
        sLabel                        matlab.ui.control.Label
        ResetinitmagCheckBox          matlab.ui.control.CheckBox
        B1inhomEditField              matlab.ui.control.NumericEditField
        B1inhomEditFieldLabel         matlab.ui.control.Label
        LoadfromEditField_pulse       matlab.ui.control.EditField
        LoadfromEditField_2Label_2    matlab.ui.control.Label
        PostpulsedelayEditField       matlab.ui.control.NumericEditField
        PostpulsedelayEditFieldLabel  matlab.ui.control.Label
        PulsenumberEditField          matlab.ui.control.NumericEditField
        PulsenumberEditFieldLabel     matlab.ui.control.Label
        PulsedelayEditField           matlab.ui.control.NumericEditField
        PulsedelayEditFieldLabel      matlab.ui.control.Label
        PulsedurationEditField        matlab.ui.control.NumericEditField
        PulsedurationEditFieldLabel   matlab.ui.control.Label
        PulseshapeDropDown            matlab.ui.control.DropDown
        PulseshapeDropDownLabel       matlab.ui.control.Label
        B1rmsEditField                matlab.ui.control.NumericEditField
        B1rmsEditFieldLabel           matlab.ui.control.Label
        B1maxEditField                matlab.ui.control.NumericEditField
        B1maxLabel                    matlab.ui.control.Label
        uTLabel_2                     matlab.ui.control.Label
        msLabel_3                     matlab.ui.control.Label
        msLabel_2                     matlab.ui.control.Label
        msLabel                       matlab.ui.control.Label
        uTLabel                       matlab.ui.control.Label
        AdvanceTab                    matlab.ui.container.Tab
        PulsephasecycEditField        matlab.ui.control.EditField
        PulsephasecycEditFieldLabel   matlab.ui.control.Label
        degLabel                      matlab.ui.control.Label
        CompaccumphaseCheckBox        matlab.ui.control.CheckBox
        SelectedpulseshapeEditField   matlab.ui.control.EditField
        SelectedpulseshapeLabel       matlab.ui.control.Label
        NoteOtherpulseparametersaresetunderBasicLabel  matlab.ui.control.Label
        Shapepara2EditField           matlab.ui.control.NumericEditField
        Shapepara2EditFieldLabel      matlab.ui.control.Label
        Shapepara1EditField           matlab.ui.control.NumericEditField
        Shapepara1EditFieldLabel      matlab.ui.control.Label
        RFDesignButton                matlab.ui.control.Button
        ShapedRFLabel                 matlab.ui.control.Label
        SequenceLabel                 matlab.ui.control.Label
        RFsamplesEditField            matlab.ui.control.NumericEditField
        RFsamplesEditFieldLabel       matlab.ui.control.Label
        LoadfromEditField_freqoffs    matlab.ui.control.EditField
        LoadfromEditField_2Label      matlab.ui.control.Label
        FreqoffsmodeDropDown          matlab.ui.control.DropDown
        FreqoffsmodeDropDownLabel     matlab.ui.control.Label
        FreqoffsM0EditField           matlab.ui.control.NumericEditField
        FreqoffsM0EditFieldLabel      matlab.ui.control.Label
        FreqoffsstepEditField         matlab.ui.control.NumericEditField
        FreqoffsstepEditFieldLabel    matlab.ui.control.Label
        FreqoffstoEditField           matlab.ui.control.NumericEditField
        FreqoffstoEditFieldLabel      matlab.ui.control.Label
        FreqoffsfromEditField         matlab.ui.control.NumericEditField
        FreqoffsfromEditFieldLabel    matlab.ui.control.Label
        B0inhomEditField              matlab.ui.control.NumericEditField
        B0inhomEditFieldLabel         matlab.ui.control.Label
        B0EditField                   matlab.ui.control.NumericEditField
        B0DropDownLabel               matlab.ui.control.Label
        ppmLabel_6                    matlab.ui.control.Label
        SaveparamButton_2             matlab.ui.control.Button
        LoadparamButton_2             matlab.ui.control.Button
        ppmLabel_5                    matlab.ui.control.Label
        ppmLabel_4                    matlab.ui.control.Label
        ppmLabel_3                    matlab.ui.control.Label
        ppmLabel                      matlab.ui.control.Label
        TLabel                        matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        OutputLogTextArea             matlab.ui.control.TextArea
        OutputLogTextAreaLabel        matlab.ui.control.Label
        SaveparamButton               matlab.ui.control.Button
        LoadparamButton               matlab.ui.control.Button
        T2_Other3                     matlab.ui.control.NumericEditField
        T2_Other2                     matlab.ui.control.NumericEditField
        T2_Other1                     matlab.ui.control.NumericEditField
        T2_Guan                       matlab.ui.control.NumericEditField
        T2_MT                         matlab.ui.control.NumericEditField
        T2_NOE                        matlab.ui.control.NumericEditField
        T2_Amide                      matlab.ui.control.NumericEditField
        T2_Water                      matlab.ui.control.NumericEditField
        T1_Other3                     matlab.ui.control.NumericEditField
        T1_Other2                     matlab.ui.control.NumericEditField
        T1_Other1                     matlab.ui.control.NumericEditField
        T1_Guan                       matlab.ui.control.NumericEditField
        T1_MT                         matlab.ui.control.NumericEditField
        T1_NOE                        matlab.ui.control.NumericEditField
        T1_Amide                      matlab.ui.control.NumericEditField
        T1_Water                      matlab.ui.control.NumericEditField
        T2sLabel                      matlab.ui.control.Label
        T1sLabel                      matlab.ui.control.Label
        w_Other3                      matlab.ui.control.NumericEditField
        w_Other2                      matlab.ui.control.NumericEditField
        w_Other1                      matlab.ui.control.NumericEditField
        w_Guan                        matlab.ui.control.NumericEditField
        w_MT                          matlab.ui.control.NumericEditField
        w_NOE                         matlab.ui.control.NumericEditField
        w_Amide                       matlab.ui.control.NumericEditField
        w_Water                       matlab.ui.control.NumericEditField
        k_Other3                      matlab.ui.control.NumericEditField
        k_Other2                      matlab.ui.control.NumericEditField
        k_Other1                      matlab.ui.control.NumericEditField
        k_Guan                        matlab.ui.control.NumericEditField
        k_MT                          matlab.ui.control.NumericEditField
        k_NOE                         matlab.ui.control.NumericEditField
        k_Amide                       matlab.ui.control.NumericEditField
        k_Water                       matlab.ui.control.NumericEditField
        Other3Label                   matlab.ui.control.Label
        Other2Label                   matlab.ui.control.Label
        Other1Label                   matlab.ui.control.Label
        GuanLabel                     matlab.ui.control.Label
        MTLabel                       matlab.ui.control.Label
        NOELabel                      matlab.ui.control.Label
        AmideLabel                    matlab.ui.control.Label
        WaterLabel                    matlab.ui.control.Label
        f_Other3                      matlab.ui.control.NumericEditField
        f_Other2                      matlab.ui.control.NumericEditField
        f_Other1                      matlab.ui.control.NumericEditField
        f_Guan                        matlab.ui.control.NumericEditField
        f_MT                          matlab.ui.control.NumericEditField
        f_NOE                         matlab.ui.control.NumericEditField
        f_Amide                       matlab.ui.control.NumericEditField
        f_Water                       matlab.ui.control.NumericEditField
        wppmLabel                     matlab.ui.control.Label
        kHzLabel                      matlab.ui.control.Label
        fMLabel                       matlab.ui.control.Label
        PoolLabel                     matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ZspectraTab                   matlab.ui.container.Tab
        StopButton                    matlab.ui.control.Button
        SavedataButton                matlab.ui.control.Button
        SaveplotButton                matlab.ui.control.Button
        ClearplotButton               matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        SaturationSequenceTab         matlab.ui.container.Tab
        PlotButton_Seq                matlab.ui.control.Button
        UIAxes_Seq                    matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        LoadsettingButton             matlab.ui.control.Button
        DisplayLabel                  matlab.ui.control.Label
        ExperimentLabel               matlab.ui.control.Label
        ClearsettingButton            matlab.ui.control.Button
        ExpsettingButton              matlab.ui.control.Button
        GaussNoiseOnCheckBox          matlab.ui.control.CheckBox
        sigmaEditField                matlab.ui.control.NumericEditField
        sigmaLabel                    matlab.ui.control.Label
        GaussnoiseSwitchLabel         matlab.ui.control.Label
        FreqOffs_precise              matlab.ui.control.NumericEditField
        SizeSlider                    matlab.ui.control.Slider
        SizeSliderLabel               matlab.ui.control.Label
        ShapeLabel                    matlab.ui.control.Label
        FreqoffsppmSlider             matlab.ui.control.Slider
        FreqoffsppmLabel              matlab.ui.control.Label
        GroupsNumber                  matlab.ui.control.NumericEditField
        expparaLabel_3                matlab.ui.control.Label
        shapeDropDown                 matlab.ui.control.DropDown
        TabGroup2                     matlab.ui.container.TabGroup
        ZvalueTab                     matlab.ui.container.Tab
        CalButton_CEST                matlab.ui.control.Button
        ShowButton_CEST               matlab.ui.control.Button
        UIAxes_2D_CEST                matlab.ui.control.UIAxes
        MTRasymTab                    matlab.ui.container.Tab
        CalButton_MTR                 matlab.ui.control.Button
        ShowButton_MTR                matlab.ui.control.Button
        UIAxes_2D_MTR                 matlab.ui.control.UIAxes
        dB0Tab                        matlab.ui.container.Tab
        ImportB0map                   matlab.ui.control.Button
        B0inhommodeButtonGroup        matlab.ui.container.ButtonGroup
        MapButton_B0                  matlab.ui.control.RadioButton
        ScalarButton_B0               matlab.ui.control.RadioButton
        RandomB0map                   matlab.ui.control.Button
        UIAxes_B0map                  matlab.ui.control.UIAxes
        rB1Tab                        matlab.ui.container.Tab
        ImportB1map                   matlab.ui.control.Button
        B1inhommodeButtonGroup        matlab.ui.container.ButtonGroup
        MapButton_B1                  matlab.ui.control.RadioButton
        ScalarButton_B1               matlab.ui.control.RadioButton
        RandomB1map                   matlab.ui.control.Button
        UIAxes_B1map                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        appVersion = '0.7'; % used for file output
        PlotButtonHitNumber = 0;
        gamma_hz = 42.5764; % gyromagnetic ratio, Hz/uT
        % gamma_hz = 267.5154109126009/2/pi;

        stopFlag = false;
        
        ZspecM0Batch = []; % for normalization
        ZspecBatch = []; % used for joint display or multiple 1D plot, [Ngroups,Noffs]
        ZspecHoldOnCell = [];
        MTRasymBatch = [];
        ZspecBatchShowStatus = 0; % 0 stands for no zspec batch is shown currently

        pulseWaveform = 1; % B1 waveform, dimensionless range in [0,1], size [N,1]
        pulseImportWaveform = 1; % B1 waveform, stores "other" pulses
        pulseCell; % calculated by loading
        freqoffs_global = reshape(-10:0.25:10,[],1); % by loading and pulseRead()
        B1inhomMap = ones(128,128); % relative ratio of B1 magnitude
        B0inhomMap = zeros(128,128); % B0 offset, unit ppm
        phantom_M0 = [];
        phantom_CEST = []; % phantom CEST image (without noise) of Ngroup, size [Nx,Ny,Nf]
        phantom_MTR = []; % phantom MTR image (without noise) of Ngroup, size [Nx,Ny,Nf]
        RFPhaseModulation = []; % RF phase modulation at different freq. offs, unit rad, size [n_pulse, n_mea]
        phantomExpSetting = {}; % cell array [5,1] to store phantom experiment setting on [f,k,w,T1,T2]
                                    % each element is a table defined in Experiment setting button
    end
    
    methods (Access = private)
        
        function magn_end = bmesolver(~,magn_field, gamma_hz, pools, pulse_cell, pulse_tpost, offs, magn0, accuracy)
        % Bloch-Mcconnell Equations (BME) solver
        % DATE: 2023/05/20
        % AUTHOR: Jianpan Huang - jianpanhuang@outlook.com
        %
        % INPUT:
        % magn_field: magnetic field in Tesla
        % gamma: gyromagnetic ratio in MHz/T
        % pools = {pool1; pool2; pool3; pool4;...}; pool1 is always free water pool
        % pool = {name, T1, T2, exchange rate, chemical shift, proton fraction};
        %         name: pool name
        %         T1: T1 of the pool
        %         T2: T2 of the pool
        %         exchange rate: exchange rate of the exchangable proton, in Hz
        %         chemicalShift: chemical shift of the exchangable proton, in ppm
        %         proton fraction: proton fraction of the exchangable proton, 0～1
        % pulseCell: {pulse1, pulse2, pulse3,...}
        %     pulse: [power1,phase1,duration1;power2,phase2,duration2;...]
        %     power: in Hz, 0 for no pulse but delay
        %     phase: (0:x, pi/2:y, pi:-x, 3*pi/2:-y) 
        %     duration: in s 
        % pulse_repeat: number of repeat of the pulses in pulseCell
        % pulse_tpost: post-pulse delay, in s
        % sequence: [pulse1, pulse2,...] -> [pulse1, pulse2,...] -> ... -> tpost -> acquisition
        %           \_________________________________________________/
        %                        repeat pulse_repeat times
        % offs: frequency offset of the saturation pulse, in ppm
        % magn0: initial magnetization vector in the follwoing order
        %       [x(water); x(exch1); x(exch2);...
        %       y(water); y(exch1); y(exch2);...
        %       z(water); z(exch1); z(exch2);...]
        %       z(water) = 1;
        %       0: thermal equilibrium
        % accuracy: significance number for freq. offset [Hz]
        %
        % OUTPUT:
        % magn_end: three dimensional matrix of magnetization vector after the pulse
        %       dim 1: [x0; x1; x2; ...y0; y1; y2;...z0; z1; z2; ...]
        %       dim 2: [after pulse 1; after pulse 2; after pulse 3;...]
        %       dim 3: [after repeat 1; after repeat 2; after repeat 3;...]
        %
        % NOTE:
        % The format of BME is the same as JMR 154, 157–160 (2002) 
        
        if ~exist('accuracy','var') || isempty(accuracy)
            accuracy = 6; % consistent to SEQ file defined by Pulseq-CEST
        end
        pools = pools(:);
        water_pool = pools{1};
        exch_pools = pools(2:end);
        pool_num = length(pools);
        pulse_num = length(pulse_cell);
        
        %  fract: fractions for all pools
        fract = cellfun(@(pool) pool{6},  pools);
        %  magn0: initial magnetizations for all pools
        %         [x0; x1; x2; ...y0; y1; y2;...z0; z1; z2; ...]
        magn_ss = [zeros(pool_num*2,1); fract(:)] / fract(1);
        magn0 = reshape(magn0,[],1);
        if (size(magn0(:)) ~= 3*pool_num)
            magn0 = magn_ss;
        end
        
        % create the relaxation matrix
        r1 = cellfun(@(pool) 1/pool{2},  pools);
        r2 = cellfun(@(pool) 1/pool{3},  pools);
        r_mat = -diag([r2; r2; r1]);
        
        % create the exchange matrix
        exch2water = cellfun(@(pool)  pool{4},  exch_pools);
        water2exch = cellfun(@(pool)  pool{6}*pool{4},  exch_pools) / water_pool{6};
        exch_diag = [-sum(water2exch), -exch2water(:)'];
        exch_block = diag(exch_diag);
        exch_block(1,2:end) = exch2water(:)';
        exch_block(2:end,1) = water2exch(:);
        exch_mat = zeros(pool_num*3); % 3 dimensions
        for ndims = 1:3
            exch_mat((ndims-1)*pool_num+1:ndims*pool_num, (ndims-1)*pool_num+1:ndims*pool_num) = exch_block;
        end
        
        % create the chemical shift (in ppm) matrix
        dw_arr = cellfun(@(pool) pool{5},  pools); 
        dw_pool = 2*pi*magn_field*gamma_hz*dw_arr;   
        
        % create the evolution matrix for the pulse
        if ~isempty(pulse_cell)
            for npulsenum = 1:pulse_num
                pulse = pulse_cell{npulsenum};
    
                % chemical shift evolution matrix
                dw_rf = 2*pi * round(magn_field*gamma_hz*offs,accuracy,'significant'); % consistent with .seq
                dw = dw_pool - dw_rf * (max(pulse(:,1)) > 0); % reset dw_rf if B1==0
                cs_mat = diag([-dw; zeros(pool_num,1)],pool_num) ...
                          + diag([dw; zeros(pool_num,1)], -pool_num); 
    
                for npulsestep = 1:size(pulse,1)
                    xpul = ones(pool_num,1) * pulse(npulsestep,1) * cos(pulse(npulsestep,2)) * pi * 2;
                    ypul = ones(pool_num,1) * pulse(npulsestep,1) * sin(pulse(npulsestep,2)) * pi * 2;
                    zpul = zeros(pool_num,1);
                    pulse_mat = diag([-zpul;-xpul], pool_num) + diag(ypul, pool_num*2) + ...
                                diag([zpul;xpul], -pool_num) + diag(-ypul, -pool_num*2);
                    evol_mat = r_mat + exch_mat + cs_mat + pulse_mat;
                    if npulsestep==1&&npulsenum==1
                        magn_init = magn0;
                    elseif npulsestep==1&&npulsenum~=1
                        magn_init = magn_pulse(:,end,end);
                    else
                        magn_init = magn_pulsestep(:,npulsestep-1);
                    end
                    % Sove the equation using the formal solution: magn = (magn + ss_mat)*exp(evol_mat*t) - ss_mat
                    % where ss_mat = evol_mat_inv*(-r_mat*magn0), times evol_mat on both sides
                    % we get evol_mat*ss_mat = -r_mat*magn0, hence ss_mat = evol_mat/(-r_mat*magn0)
                    t = pulse(npulsestep,3);
                    ss_mat = mldivide(evol_mat, -r_mat*magn_ss);
                    % ss_mat = inv(evol_mat)*(-r_mat)*magn_ss;
                    magn_pulsestep(:,npulsestep) = expm(evol_mat*t)*(magn_init + ss_mat) - ss_mat;
                end
                magn_pulse(:,npulsenum) = magn_pulsestep(:,npulsestep);
            end
            magn_end(:,1) = magn_pulse(:,pulse_num);
        else
            magn_end = magn0;
        end
        
        % calculate the evolution after pulse if there is a tpost
        if pulse_tpost ~= 0
            xpul = zeros(pool_num,1);
            ypul = zeros(pool_num,1);
            zpul = zeros(pool_num,1);
            pulse_mat = diag([-zpul;-xpul], pool_num) + diag(ypul, pool_num*2) + ...
                        diag([zpul;xpul], -pool_num) + diag(-ypul, -pool_num*2);
            cs_mat = diag([-dw_pool; zeros(pool_num,1)],pool_num) ...
                          + diag([dw_pool; zeros(pool_num,1)], -pool_num); 
            evol_mat = r_mat + exch_mat + cs_mat + pulse_mat;
            magn_init = magn_end;
            % Sove the equation using the formal solution: magn = (magn + ss_mat)*exp(evol_mat*t) - ss_mat
            % where ss_mat = evol_mat_inv*(-r_mat*magn0), times evol_mat on both sides
            % we get evol_mat*ss_mat = -r_mat*magn0, hence ss_mat = evol_mat/(-r_mat*magn0)
            t = pulse_tpost;
            ss_mat = mldivide(evol_mat, -r_mat*magn_ss);
            % ss_mat = inv(evol_mat)*(-r_mat)*magn0;
            magn_end(:,1) = expm(evol_mat*t)*(magn_init + ss_mat) - ss_mat;
        end
        end
        
        function [zspec,flag] = bmesimulate(app,offs,pools,progress_on)
            % INPUT:
            %   offs: frequency offsets, size [N,1]
            %   pools:
            % OUTPUT:
            %   zspec: Z-spectrum, size [N,1]

            flag = 0; % stopping flag

            if ~exist('progress_on','var') || isempty(progress_on)
                progress_on = 0;
            end
            % saturation settings
            b0 = app.B0EditField.Value;
            pulse_cell = app.pulseCell;
            if isempty(pulse_cell)
                pulseReadFromUI(app);
                pulse_cell = app.pulseCell;
            end
            pulse_tpost = app.PostpulsedelayEditField.Value / 1000; %s 
            
            % pools T1/T2 check
            pools = poolsCheck(app,pools);

            % cest simulation for Z-spectrum
            zspec = zeros(length(offs),1);
            
            % B1/B0 inhomo setting
            if app.ScalarButton_B0.Value == 1
                pools = cellfun(@(x) {x{1:4}, ...
                    x{5}-app.B0inhomEditField.Value, ...
                    x{6}}, pools, 'UniformOutput', false);
            end
            if app.ScalarButton_B1.Value == 1
                pulse_cell = cellfun(@(x) x .* [app.B1inhomEditField.Value,1,1],pulse_cell,'UniformOutput' ,false);
            end

            % simulation
            Trec = app.RecoverytimeEditField.Value; % s
            outputLogTemp = app.OutputLogTextArea.Value;
            for n = 1:length(offs)
                if app.stopFlag == true
                    flag = 1;
                    return;
                end
                if progress_on == 1
                    percent = n/length(offs)*100;
                    updateLogRealtime(app,[outputLogTemp;"Calculation process: " + num2str(percent,'%.2f') + "%"]);
                end
                offs_temp = offs(n);

                % add RF accumulated phase
                pulsetemp = addRFAccumPhase(app,pulse_cell,offs_temp);

                % calculation
                if app.ResetinitmagCheckBox.Value == 1
                    magn = bmesolver(app, b0, app.gamma_hz, pools, pulsetemp, pulse_tpost, offs_temp, 0);
                else
                    % consider TR delay
                    if n == 1
                        magn = bmesolver(app, b0, app.gamma_hz, pools, pulsetemp, pulse_tpost, offs_temp, 0);
                    else
                        magn = bmesolver(app, b0, app.gamma_hz, pools, pulsetemp, pulse_tpost, offs_temp, magn_rec);
                    end
                    magn_spoil = magn; magn_spoil(1:end-length(pools))=0;
                    magn_rec = bmesolver(app, b0, app.gamma_hz, pools, [], Trec, offs_temp, magn_spoil);
                end
                zspec(n,1) = magn(length(pools)*2+1, end, end);
            end
            if progress_on == 1
                app.OutputLogTextArea.Value = [outputLogTemp;string(datetime('now','Format','HH:mm:ss'))+...
                                          ": Calculation done"];
            end
            scroll(app.OutputLogTextArea,'bottom');
        end
        
        function pools = poolsRead(app)
            % Read parameters of Exchange settings located at the center panel
            % store concentration as relative concentration (0~1)
            % concentration conversion:
            %       concentration of 1H in H2O: 111M
            %       amide concentration (assumming): ~100mM
            %       relative concentration of amide: 100mM/111M = 0.0009009
            %% exchange settings
            % unit convention used in bmesolver
            % %        {name,            t1 [s],   t2 [s],    exch rate [Hz],  dw [ppm],    fraction (0~1)}
            % water  = {'water',        1.0,      0.04       1,               0,           1};
            % amide  = {'amide',        1.0,      0.1        50,              3.5,         0.0009009};
            % noe    = {'noe',          1.3,      0.005,     20,              -3.5,        0.0045};
            % mt     = {'mt',           1.0,      4.0e-05,   30,              -2.5,        0.1351};
            % guanid = {'guanidine',    1.0,      0.1,       1000,            2.0,         0.0009009};
            % pools = {water; mt; amide; guanid; noe};

            % unit used in GUI
            %        {name,            t1 [s],              t2 [s],                 exch rate [Hz],       dw [ppm],             relative fraction   }
            water  = {'Water',      app.T1_Water.Value,     app.T2_Water.Value,     app.k_Water.Value,    app.w_Water.Value,    app.f_Water.Value/111};
            amide  = {'Amide',      app.T1_Amide.Value,     app.T2_Amide.Value,     app.k_Amide.Value,    app.w_Amide.Value,    app.f_Amide.Value/111};
            noe    = {'NOE',        app.T1_NOE.Value,       app.T2_NOE.Value,       app.k_NOE.Value,      app.w_NOE.Value,      app.f_NOE.Value/111};
            mt     = {'Mt',         app.T1_MT.Value,        app.T2_MT.Value,        app.k_MT.Value,       app.w_MT.Value,       app.f_MT.Value/111};
            guanid = {'Guan',       app.T1_Guan.Value,      app.T2_Guan.Value,      app.k_Guan.Value,     app.w_Guan.Value,     app.f_Guan.Value/111};
            other1 = {'Other1',     app.T1_Other1.Value,    app.T2_Other1.Value,    app.k_Other1.Value,   app.w_Other1.Value,   app.f_Other1.Value/111};
            other2 = {'Other2',     app.T1_Other2.Value,    app.T2_Other2.Value,    app.k_Other2.Value,   app.w_Other2.Value,   app.f_Other2.Value/111};
            other3 = {'Other3',     app.T1_Other3.Value,    app.T2_Other3.Value,    app.k_Other3.Value,   app.w_Other3.Value,   app.f_Other3.Value/111};
            pools = {water; amide; noe; mt; guanid; other1; other2; other3};

        end

        function phantom_roi = Phantom2DCal_ROI(app)
            % calculate phantom mask based on UI paras
            % OUTPUT:
            %   phantom_roi: phantom mask, size [Ncolumn,Nrow]*Np
            %       Ncolumn =  Nrow = ceil(sqrt(Ngroups)), Np = 128

            % CEST images setting
            radiusR = app.SizeSlider.Value;
            Np = 128; % size of subimage
            radius = 0.5*radiusR*Np;

            % load shape paras
            Ngroups = app.GroupsNumber.Value;
            shape = app.shapeDropDown.Value;
            Ncolumn = ceil(sqrt(Ngroups));
            Nrow = Ncolumn;

            % mask calculation
            phantom_roi = zeros(Ncolumn*Np, Nrow*Np);
            crossFunc = @(vec1,vec2_x,vec2_y) vec1(1)*vec2_y-vec2_x*vec1(2); % x1*y2-x2*y1
            for idx = 1:Ngroups
                rowRange = (ceil(idx/Ncolumn)-1)*Np + (1:Np); % [1,1,1,2,2,2]
                colRrange = mod(idx-1,Nrow)*Np + (1:Np); % [1,2,3,1,2,3]
                [X,Y] = meshgrid(0.5:Np-0.5,0.5:Np-0.5);
                switch shape
                    case 'Circle'
                        phantom_sub = (X-0.5*Np).^2+(Y-0.5*Np).^2 <= radius^2;
                    case 'Square'
                        phantom_sub = (abs(X-0.5*Np) <= radius) & (abs(Y-0.5*Np) <=radius);
                    case 'Triangle'
                        % three vertices in anticlockwise
                        pA = Np * [0.5,0.5-0.5*radiusR];
                        pB = Np * [0.5-cos(pi/6)*0.5*radiusR,0.5+sin(pi/6)*0.5*radiusR];
                        pC = Np * [0.5+cos(pi/6)*0.5*radiusR,0.5+sin(pi/6)*0.5*radiusR];

                        r1 = crossFunc(pB-pA,X-pA(1),Y-pA(2)); % AB,AO
                        r2 = crossFunc(pC-pB,X-pB(1),Y-pB(2)); % BC,BO
                        r3 = crossFunc(pA-pC,X-pC(1),Y-pC(2)); % CA,CO
                        phantom_sub = sign(r1) == sign(r2) & sign(r1) == sign(r3);
                end
                phantom_roi(rowRange,colRrange) = double(phantom_sub);
            end

        end

        function [phantom_2D_CEST,phantom_2D_MTR] = Phantom2DCal_CEST(app,phantom_roi,zspec,MTRasym)
            % calculate CEST images with specific Z/MRT value, with noise
            % INPUT:
            %   phantom_roi: phantom mask, size [Nx,Ny] = [Ncolumn,Nrow]*Np
            %   zspec: [Ngroups,Nf]
            %   MTRasym: [Ngroups,Nf]
            % OUTPUT:
            %   phantom_2D_CEST: CEST images at ex_offs, [Nx,Ny,Nf]
            %   phantom_2D_CEST: MTRasym images at ex_offs, [Nx,Ny,Nf]

            % CEST images setting
            Np = 128; % size of subimage
            Nf = size(zspec,2);
            Ngroups = app.GroupsNumber.Value;
            Ncolumn = ceil(sqrt(Ngroups));
            Nrow = Ncolumn;
            
            % calculate CEST signal for each group
            phantom_2D_CEST = zeros(Ncolumn*Np, Nrow*Np,Nf);
            phantom_2D_MTR = zeros(Ncolumn*Np, Nrow*Np,Nf);
            for offsidx = 1:Nf    
                for idx = 1:Ngroups
                    rowRange = (ceil(idx/Ncolumn)-1)*Np + (1:Np); % [1,1,1,2,2,2]
                    colRrange = mod(idx-1,Nrow)*Np + (1:Np); % [1,2,3,1,2,3]
                    phantom_2D_CEST(rowRange,colRrange,offsidx) = phantom_roi(rowRange,colRrange) * zspec(idx,offsidx);
                    phantom_2D_MTR(rowRange,colRrange,offsidx) = phantom_roi(rowRange,colRrange) * MTRasym(idx,offsidx);
                end
            end

        end

        
        function Phantom2DPlot(app, ind_offs)
            % plot phantom images (calculated by Cal Button) at specific freq. offs
            % INPUT:
            %   ind_offs: index of frequency offsets array
            %   app.phantom_CEST: [Nx,Ny,Nf]
            %   app.phantom_MTR: [Nx,Ny,Nf]
            
            phantom_2D_CEST = app.phantom_CEST;
            phantom_2D_MTR = app.phantom_MTR;

            if ~isempty(phantom_2D_CEST)
                % add noise for display
                CEST_noise = zeros(512,512);
                MTR_noise = zeros(512,512);
                if app.GaussNoiseOnCheckBox.Value == 1
                    sigma = app.sigmaEditField.Value;
                    CEST_noise = sigma * randn(size(CEST_noise));
                    MTR_noise = sqrt(2) * sigma * randn(size(CEST_noise)); % double noise
                end
                
                % images display
                phantom_adjust_CEST = imresize(phantom_2D_CEST(:,:,ind_offs),[512,512],"nearest") + CEST_noise;
                imshow(abs(phantom_adjust_CEST),[],'Parent',app.UIAxes_2D_CEST,'InitialMagnification','fit')
    
                phantom_adjust_MTR = imresize(phantom_2D_MTR(:,:,ind_offs),[512,512],"nearest") + MTR_noise;
                imshow(phantom_adjust_MTR,[],'Parent',app.UIAxes_2D_MTR,'InitialMagnification','fit')
                colormap(app.UIAxes_2D_MTR,jet);
                colorbar(app.UIAxes_2D_MTR);
                clim(app.UIAxes_2D_MTR,'auto');
            end
        end
        
        function zspec1DPlot_joint(app, ex_offs)
            % plot zspec batch (calculated by Cal Button) along with 2D CEST images
            % only loading and plotting, no BME calculation involved
            % Input:
            %   ex_offs: frequency offset of 2D CEST images, scalar value 
            %   app.ZspecBatch: [Ngroups, Nf]
            %   app.MTRasymBatch: [Ngroups, Nf]

            % (1) clear UIAxes for zspec
            UIAxesInitialize(app)

            % (2) load zspec batch and freq offs in 2D CEST images
            offs_show = app.freqoffs_global;
            zspecm0batch = app.ZspecM0Batch;
            zspecbatch = app.ZspecBatch; % [Ngroups, Noffs]
            MTRasymbatch = app.MTRasymBatch;
            if ~isempty(zspecbatch)
                % add noise for display
                if app.GaussNoiseOnCheckBox.Value == 1
                    sigma = app.sigmaEditField.Value;
                    zspecbatch = zspecbatch + sigma*randn(size(zspecbatch));
                    MTRasymbatch = MTRasymbatch + sqrt(2)*sigma*randn(size(MTRasymbatch));
                end
                
                % (3) plot [zspec batch, MTRasym, freq offs] mark in UIAxes
                app.UIAxes.XLim = [min(app.freqoffs_global,[],'all'), max(app.freqoffs_global,[],'all')];
                app.UIAxes.XTickMode = "auto";
                app.UIAxes.YLim = [min(zspecbatch,[],'all'), max(zspecbatch,[],'all')];
                app.UIAxes.YTickMode = "auto";
    
                hold(app.UIAxes,"on");
                yyaxis(app.UIAxes,"left");ylabel(app.UIAxes,"Z");
                app.UIAxes.YColor = 'black';
                colororder(app.UIAxes,jet(size(zspecbatch,1)));
                app.UIAxes.LineStyleOrder = "-";
                for idx = 1:size(zspecbatch,1)
                    [x,I] = sort(reshape(offs_show,1,[]),'ascend');
                    y = zspecbatch(idx,:)./zspecm0batch(idx); y = y(I);
                    plot(app.UIAxes, x, y,'-','LineWidth',1);
                end
                plot(app.UIAxes, [ex_offs,ex_offs],[min(zspecbatch,[],'all'), max(zspecbatch,[],'all')],'-r','LineWidth',1.5);
                
                % (4) plot MTRasym
                yyaxis(app.UIAxes,"right");ylabel(app.UIAxes,"MTRasym");
                colororder(app.UIAxes,jet(size(zspecbatch,1)));
                app.UIAxes.LineStyleOrder = ":";
                % app.UIAxes.YLim = [-0.2,1.0];
                MTRmin = min(MTRasymbatch./repmat(zspecm0batch,1,length(offs_show)),[],'all');
                MTRmax = max(MTRasymbatch./repmat(zspecm0batch,1,length(offs_show)),[],'all');
                app.UIAxes.YLim = [MTRmin*1.1,MTRmax*5];
                for idx = 1:size(zspecbatch,1)
                    [x,I] = sort(reshape(offs_show,1,[]),'ascend');
                    y = MTRasymbatch(idx,:)./zspecm0batch(idx); y = y(I);
                    plot(app.UIAxes, x, y,':','LineWidth',1);
                end

                hold(app.UIAxes,"off");
                yyaxis(app.UIAxes,"left");

                % legend text
                legend(app.UIAxes,"Phantom " + string(1:size(zspecbatch,1)),'fontsize',6)
                
                app.ZspecBatchShowStatus = 1;
            end
        end
        
        function [zspecBatch_P,zspecBatch_N, flag] = zspecBatchCalculatefromExpUI_pair(app,ex_offs)
            % Calculate multi zspec at the given experiment parameters and freq offs
            % Input:
            %   ex_offs: frequency offsets, size [Noffs,1]
            % Output:
            %   zspecBatch_P: calculated zspec array at  ex_offs, size [Ngroups,Noffs]
            %   zspecBatch_N: calculated zspec array at -ex_offs, size [Ngroups,Noffs]

            flag = 0; % stop flag

            % saturation settings
            b0 = app.B0EditField.Value;
            pulse_cell = app.pulseCell;
            if isempty(pulse_cell)
                pulseReadFromUI(app);
                pulse_cell = app.pulseCell;
            end
            pulse_tpost = app.PostpulsedelayEditField.Value / 1000; %s 

            % load experiment value
            poolsArr = phanExpSetToPoolsArr(app);
            Ngroups = app.GroupsNumber.Value;

            % B1/B0 inhomo setting
            if app.ScalarButton_B0.Value == 1
                for groupIdx = 1:Ngroups
                    poolsArr{groupIdx} = cellfun(@(x) {x{1:4}, ...
                        x{5}-app.B0inhomEditField.Value, ...
                        x{6}}, poolsArr{groupIdx}, 'UniformOutput', false);
                end
            end
            if app.ScalarButton_B1.Value == 1
                pulse_cell = cellfun(@(x) x .* [app.B1inhomEditField.Value,1,1],pulse_cell,'UniformOutput' ,false);
            end

            % calculate zspec for each group
            zspecBatch_P = zeros(Ngroups,length(ex_offs));
            zspecBatch_N = zeros(Ngroups,length(ex_offs));

            Trec = app.RecoverytimeEditField.Value; % s
            outputLogTemp = app.OutputLogTextArea.Value;
            for idx = 1:Ngroups
                percent = (idx-1)/Ngroups*100;
                updateLogRealtime(app,[outputLogTemp;"Calculation process: " + num2str(percent,'%.2f') + "%"]);

                pools = poolsArr{idx};
	            pools = poolsCheck(app,pools);
                for n = 1:length(ex_offs)
                    offs_temp = ex_offs(n);

                    % add RF accumulated phase
                    pulsetemp = addRFAccumPhase(app,pulse_cell,offs_temp);

                    % calculation
                    if app.ResetinitmagCheckBox.Value == 1
                        magn_P = bmesolver(app,b0, app.gamma_hz, pools, pulsetemp, pulse_tpost, offs_temp, 0);
                        magn_N = bmesolver(app,b0, app.gamma_hz, pools, pulsetemp, pulse_tpost, -offs_temp, 0);
                    else
                        % consider TR delay
                        if n == 1
                            magn_P = bmesolver(app,b0, app.gamma_hz, pools, pulsetemp, pulse_tpost, offs_temp, 0);
                            magn_N = bmesolver(app,b0, app.gamma_hz, pools, pulsetemp, pulse_tpost, -offs_temp, 0);
                        else
                            magn_P = bmesolver(app,b0, app.gamma_hz, pools, pulsetemp, pulse_tpost, offs_temp, magn_P_rec);
                            magn_N = bmesolver(app,b0, app.gamma_hz, pools, pulsetemp, pulse_tpost, -offs_temp, magn_N_rec);
                        end
                        magn_P_spoil = magn_P; magn_P_spoil(1:end-length(pools))=0;
                        magn_N_spoil = magn_N; magn_N_spoil(1:end-length(pools))=0;
                        magn_P_rec = bmesolver(app, b0, app.gamma_hz, pools, [], Trec, offs_temp, magn_P_spoil);
                        magn_N_rec = bmesolver(app, b0, app.gamma_hz, pools, [], Trec, offs_temp, magn_N_spoil);
                    end
                    
                    zspecBatch_P(idx,n) = magn_P(length(pools)*2+1, end, end);
                    zspecBatch_N(idx,n) = magn_N(length(pools)*2+1, end, end);
                    
                end
                if app.stopFlag == true
                    flag = 1;
                    return;
                end
            end
            app.OutputLogTextArea.Value = [outputLogTemp;string(datetime('now','Format','HH:mm:ss'))+": Calculation done"];
            scroll(app.OutputLogTextArea,'bottom');
        end

        function zspecBatch = zspecBatchCalculatefromExpUI_single(app,ex_offs)
            % Calculate multi zspec at the given experiment parameters and freq offs
            %    No calculation progress shown
            % Input:
            %   ex_offs: frequency offsets, size [1,1]
            % Output:
            %   zspecBatch: calculated zspec array at Ngroups paras, size [Ngroups,Noffs]

            % load experiment value
            poolsArr = phanExpSetToPoolsArr(app);
            Ngroups = app.GroupsNumber.Value;

            % calculate zspec for each group
            zspecBatch = zeros(Ngroups,length(ex_offs));
            for idx = 1:Ngroups
                pools = poolsArr{idx};
                pools = poolsCheck(app,pools);
                zspecBatch(idx,:) = bmesimulate(app, ex_offs, pools,0);
            end
        end
        
        function pulsetemp = addRFAccumPhase(app,pulse_cell,offs_temp)
            % add RF accumulated phase at offs_temp
            %   the accum_phase origins from off-resonance multi-RF
            %   because the simulation is performed in reference frame
            % INPUT:
            %   pulse_cell
            %   offs_temp: current RF frequency offset, [ppm]
    
            b0 = app.B0EditField.Value;
            pulse_repeat = app.PulsenumberEditField.Value;
            pulseblock_dur = app.PulsedurationEditField.Value / 1000; %s
            compe_phase = 0; % compensated RF phase
            accum_phase = 0; % ideal accumulated phase from rotation frame
            for i = 1:pulse_repeat
                % (0) apply RF phase to pulse_cell
                %   the compe_phase has limited accuracy
                pulsetemp = pulse_cell;
                temp = pulsetemp{2*i-1};
                temp(:,2) = temp(:,2)...
                    + round(compe_phase,6,'significant')*(app.CompaccumphaseCheckBox.Value==1)...
                    - accum_phase;
                pulsetemp{2*i-1} = temp;
        
                % (1) compensate RF phase in .seq file
                compe_phase = compe_phase + (offs_temp*b0*app.gamma_hz) * 2 * pi * pulseblock_dur;
                compe_phase = mod(compe_phase,2*pi);
            
                % (2) since we simulate in reference frame, we need to take care of the accumuated phase
                phase_degree = pulseblock_dur * 360 * offs_temp * b0*app.gamma_hz;
                phase_degree = mod(phase_degree,360);
                accum_phase = accum_phase + phase_degree / 180 * pi;
            end
            
        end
        
        
        % Function to adjust legend font size
        function adjustLegendFontSize(app)
            % Get the number of legend entries
            numEntries = app.PlotButtonHitNumber;
    
            % Set the base font size and scaling factor
            baseFontSize = 12; % Base font size
            scaleFactor = 0.9; % Scale factor for each additional entry
    
            % Calculate the new font size
            newFontSize = max(6, baseFontSize * (scaleFactor ^ (numEntries - 1)));
    
            % Apply the new font size to the legend
            legend(app.UIAxes,'FontSize',newFontSize)
            % app.UIAxes.FontSize = newFontSize;

        end
        
        function [existflag,value] = assignValueToUI(~, uiHandle, targetText, keyword, variableType, defaultvalue)
            % search value in text and assign value to GUI component
            %   used in loadSaturationfromCESTsimu
            % Input:
            %   uiHandle: GUI component handle, e.g. app.B0EditField
            %   targetText: [n,1] string, text to be searched
            %   keyword: keyword in SATPARA, e.g. 'B0 (T)'
            %   variable Type: 'double' or 'string'
            %   defaultvalue: default value for uiHandle
            % Ouput:
            %   existflag: 1 represents variable exists in targetText
            %   value: searched value


            % (1) search value, use default if not exist
            keywordPositions = cellfun(@(line) strfind(line, keyword), targetText, 'UniformOutput', false);
            lineIndex = find(~cellfun(@isempty, keywordPositions), 1);
            if isempty(lineIndex)
                existflag = 0;
                value = defaultvalue;
            else
                existflag = 1;

                % delete blank at both sides of ':'
                strTemp = regexprep(targetText(lineIndex),'\s+(:)\s+','$1');

                % extract value after ':'
                strTemp = strsplit(strTemp,':');
                strTemp = strTemp(end);
                switch variableType
                    case 'double'
                        value = str2double(strTemp);
                    case 'string'
                        value = strTemp;
                end
            end

            % (2) assign value
            uiHandle.Value = value;
        end
        
        function importedRF2interpRF(app)
            % interpolate imported RF based on app.RFsamplesEditField.Value

            targetLength = app.RFsamplesEditField.Value;
            oriRF = app.pulseImportWaveform;
            originalLength = length(oriRF);
            
            xOriginal = linspace(1, originalLength, originalLength);
            xTarget = linspace(1, originalLength, targetLength);
            
            app.pulseWaveform = interp1(xOriginal, oriRF, xTarget, 'linear');

        end
        
        function ConfirmCallback_UITable(app, fig_UItab)
            % confirm callback function for phantom para. setting UI table

            appendOutputLog(app, ": Phantom experiment setting completed.");
            close(fig_UItab);
        end
        
        function UpdateUITable(app, fig_UItab)
            % update UI table according to selection

            Paradropdown = findobj(fig_UItab, 'Tag', 'ParaDrop');
            uit = findobj(fig_UItab, 'Tag', 'DynamicTable');
            switch Paradropdown.Value
                case "T1 [s]"
                    uit.Data = app.phantomExpSetting{1};
                case "T2 [s]"
                    uit.Data = app.phantomExpSetting{2};
                case "k [Hz]"
                    uit.Data = app.phantomExpSetting{3};
                case "w [ppm]"
                    uit.Data = app.phantomExpSetting{4};
                case "f [M]"
                    uit.Data = app.phantomExpSetting{5};
            end
        end
        
        function UpdateTableData(app, fig_UItab)
            % update app.phantomExpSetting when UI table changed

            Paradropdown = findobj(fig_UItab, 'Tag', 'ParaDrop');
            uit = findobj(fig_UItab, 'Tag', 'DynamicTable');
            switch Paradropdown.Value
                case "T1 [s]"
                    app.phantomExpSetting{1} = uit.Data;
                case "T2 [s]"
                    app.phantomExpSetting{2} = uit.Data;
                case "k [Hz]"
                    app.phantomExpSetting{3} = uit.Data;
                case "w [ppm]"
                    app.phantomExpSetting{4} = uit.Data;
                case "f [M]"
                    app.phantomExpSetting{5} = uit.Data;
            end
        end
        
        function QuickGenerate_UITable(app, fig_UItab)
            % quickly assign UItable value

            uit = findobj(fig_UItab, 'Tag', 'DynamicTable');
            Paradropdown = findobj(fig_UItab, 'Tag', 'ParaDrop');
            Pooldropdown = findobj(fig_UItab, 'Tag', 'poolDrop');
            valueFromObj = findobj(fig_UItab, 'Tag', 'valueFrom');
            valueToObj = findobj(fig_UItab, 'Tag', 'valueTo');

            % set values with linear variation
            tableData = uit.Data;
            Ng = size(tableData,1);
            ValList = max(linspace(valueFromObj.Value,valueToObj.Value,Ng),0);
            PoolInd = strcmp(uit.ColumnName, Pooldropdown.Value);
            
            % update UI table and app.phantomExpSetting
            tableData{:, PoolInd} = reshape(ValList,[],1);
            uit.Data = tableData;
            switch Paradropdown.Value
                case "T1 [s]"
                    app.phantomExpSetting{1} = uit.Data;
                case "T2 [s]"
                    app.phantomExpSetting{2} = uit.Data;
                case "k [Hz]"
                    app.phantomExpSetting{3} = uit.Data;
                case "w [ppm]"
                    app.phantomExpSetting{4} = uit.Data;
                case "f [M]"
                    app.phantomExpSetting{5} = uit.Data;
            end
            
        end
        
        function poolsArr = phanExpSetToPoolsArr(app)
            % convert app.phantomExpSetting to poolsArr
            % Input:
            %   app.phantomExpSetting: cell array with size [5,1] for [f,k,w,T1,T2],
            %                   each element is a table with size [Ngroup, Npool]
            %                       store concentration in unit M
            %   poolsArr: cell array [Ngroup,1] for each group,
            %                   each element is the pools as bellow
            %                       store concentration as relative value (0~1)
                % %        {name,           T1 [s],   T2 [s],    exch rate [Hz],  dw [ppm],    fraction (0~1)}
                % water  = {'water',        1.0,      0.04       1,               0,           1};
                % amide  = {'amide',        1.0,      0.1        50,              3.5,         0.0009009};
                % noe    = {'noe',          1.3,      0.005,     20,              -3.5,        0.0045};
                % mt     = {'mt',           1.0,      4.0e-05,   30,              -2.5,        0.1351};
                % guanid = {'guanidine',    1.0,      0.1,       1000,            2.0,         0.0009009};
                % pools = {water; mt; amide; guanid; noe};
            
            phanExp = app.phantomExpSetting;
            [Ng,Np] = size(phanExp{1});
            poolsArr = cell(Ng,1);
            poolsNames = phanExp{1}.Properties.VariableNames; % [1,Np] cell
            for phanIdx = 1:Ng
                pools = cell(Np,1);
                for poolIdx = 1:Np
                    pools{poolIdx} = {poolsNames{poolIdx}, ... % name
                                      phanExp{1}.(poolIdx)(phanIdx),... % T1
                                      phanExp{2}.(poolIdx)(phanIdx),... % T2
                                      phanExp{3}.(poolIdx)(phanIdx),... % k
                                      phanExp{4}.(poolIdx)(phanIdx),... % dw
                                      phanExp{5}.(poolIdx)(phanIdx)/111,... % f (relative)
                                      };
                end
                poolsArr{phanIdx} = pools;
            end
        end
        
        function Plot_RFUITable(~, fig_UItab)
            RFdesign_RFsamples = findobj(fig_UItab, 'Tag', 'RFSampleEdit');
            RFdeisgn_shapeSel = findobj(fig_UItab, 'Tag', 'RFtypedrop');
            RFdesign_par1 = findobj(fig_UItab, 'Tag', 'Editpar1');
            RFdesign_par2 = findobj(fig_UItab, 'Tag', 'Editpar2');
            RFdesign_UIAxes = findobj(fig_UItab, 'Tag', 'RFdesign_UIAxes');

            my_sinc = @(x) fillmissing(sin(pi * x) ./ (pi * x),'constant',1);
            Npoint = RFdesign_RFsamples.Value;
            switch RFdeisgn_shapeSel.Value
                case "Sinc" % 2 para. (alpha, N)
                    alpha = RFdesign_par1.Value; % 0.46 for Hamming window, 0.5 for Hanning window
                    N = max(round(RFdesign_par2.Value),1); % half of the zero-crossing points, N = 1 for single-lobe sinc pulse
                    t_list = linspace(-1,1,Npoint);
                    pulse1_waveform = 1 * (1-alpha + alpha*cos(pi*t_list)) .* my_sinc(N*t_list);

                case "Gauss" % 1 para. (sigma)
                    sigma = RFdesign_par1.Value; % sigma = RFdur/7.434 for 60-dB attenuation
                    t_list = linspace(-1/2,1/2,Npoint);
                    pulse1_waveform = 1 * exp(-t_list.^2/2/sigma^2);

                case "Fermi" % 2 para. (ratio, a)
                    ratio = RFdesign_par1.Value; % defalut 16, ratio should >> 1, 
                    a = RFdesign_par2.Value; % pulse1_dur/(2*ratio+13.81) for 60-dB attenuation, Fermi -> Hard pulse when a -> 0
                    t0 = a * ratio; % describe pulse width
                    t_list = linspace(-1/2,1/2,Npoint);
                    pulse1_waveform = 1./ ( 1 + exp( (abs(t_list)-t0)/a ) );
            end

            ylabel(RFdesign_UIAxes,"B_{1} [a.u.]")
            RFdesign_UIAxes.YColor = 'black';
            RFdesign_UIAxes.YLim = [min(pulse1_waveform),max(pulse1_waveform,[],'all')*1.2];
            plot(RFdesign_UIAxes,t_list,pulse1_waveform,'k','LineWidth',1);
            RFdesign_UIAxes.YLim = [min(min(pulse1_waveform(:)),0),1.2*max(pulse1_waveform(:))];
            
        end
        
        function UpdateRFdesignUITable(~, fig_UItab)
            RFdeisgn_shapeSel = findobj(fig_UItab, 'Tag', 'RFtypedrop');
            RFdesign_par1 = findobj(fig_UItab, 'Tag', 'Editpar1');
            RFdesign_par2 = findobj(fig_UItab, 'Tag', 'Editpar2');
            RFdesign_text_par1 = findobj(fig_UItab, 'Tag', 'TextPar1');
            RFdesign_text_par2 = findobj(fig_UItab, 'Tag', 'TextPar2');

            switch RFdeisgn_shapeSel.Value
                case "Sinc"
                    RFdesign_par2.Enable = 'on';
                    RFdesign_text_par1.Text = '$\alpha$';
                    RFdesign_text_par2.Text = '$N$';
                    RFdesign_par1.Value = 0.46;
                    RFdesign_par2.Value = 1;
                case "Gauss"
                    RFdesign_par2.Enable = 'off';
                    RFdesign_text_par1.Text = '$\sigma$';
                    RFdesign_text_par2.Text = 'N/A';
                    RFdesign_par1.Value = 1/7.434;
                    RFdesign_par2.Value = 0;
                case "Fermi"
                    RFdesign_par2.Enable = 'on';
                    RFdesign_text_par1.Text = '$t_0/a$';
                    RFdesign_text_par2.Text = '$a$';
                    RFdesign_par1.Value = 16;
                    RFdesign_par2.Value = 1/(2*16+13.81);
            end
        end
        
        function Cancel_RFUITable(app, fig_UItab)
            appendOutputLog(app, ": RF design cancel");
            close(fig_UItab);
        end
        
        function Confirm_RFUITable(app, fig_UItab)
            RFdesign_RFsamples = findobj(fig_UItab, 'Tag', 'RFSampleEdit');
            RFdeisgn_shapeSel = findobj(fig_UItab, 'Tag', 'RFtypedrop');
            RFdesign_par1 = findobj(fig_UItab, 'Tag', 'Editpar1');
            RFdesign_par2 = findobj(fig_UItab, 'Tag', 'Editpar2');

            app.RFsamplesEditField.Enable = 'on';
            app.RFsamplesEditField.Value = RFdesign_RFsamples.Value;
            app.PulseshapeDropDown.Value = RFdeisgn_shapeSel.Value;
            app.Shapepara1EditField.Enable = 'on';
            app.Shapepara2EditField.Enable = 'on';
            app.Shapepara1EditField.Value = RFdesign_par1.Value;
            app.Shapepara2EditField.Value = RFdesign_par2.Value;
            switch app.PulseshapeDropDown.Value
                case "Sinc"
                    app.SelectedpulseshapeEditField.Value = "Sinc";
                    app.Shapepara1EditFieldLabel.Text = '$\alpha$';
                    app.Shapepara2EditFieldLabel.Text = '$N$';
                case "Gauss"
                    app.Shapepara2EditField.Enable = 'off';
                    app.SelectedpulseshapeEditField.Value = "Gauss";
                    app.Shapepara1EditFieldLabel.Text = '$\sigma$';
                case "Fermi"
                    app.SelectedpulseshapeEditField.Value = "Fermi";
                    app.Shapepara1EditFieldLabel.Text = '$t_0 / a$';
                    app.Shapepara2EditFieldLabel.Text = '$a$';
            end

            pulseReadFromUI(app);
            app.B1maxEditField.Value = B1rms2B1max(app, app.B1rmsEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);

            appendOutputLog(app, ": RF design completed");
            close(fig_UItab);
        end
        
        function M = Polynomial2DMatrix(~,R,D)
        % Function that provides the matrixt hat links the vector of parameters s
        % to the values of D-degree polynomial at the points defined by R.
        %
        % INPUT:    * R: (2,m) matrix that defines m 2D points of evaluation
        %           * D: degree of polynomial
        %
        % OUTPUT:   * M: (m,N) matrix that links the vector of parameters s that
        %               defines a polynomial of degree D, to its values a the n
        %               points.
        %
        % Note: In 2D we have N = (D+1)*(D+2)/2.
        %
        % Matthieu Guerquin-Kern, Biomedical Imaging Group / EPF Lausanne,
        % 31-10-2009 (dd-mm-yyyy)
        
            N = (D+1)*(D+2)/2;      % Number of parameters
            m = size(R,2);          % Number of points
            
            % construct the polynomial degrees for each parameter
            or = zeros(2,N);
            for d = 0:D
                n = d*(d+1)/2;
                for i = 0:d
                    or(:,n+i+1) = [i;d-i];
                end
            end
            
            % build matrix
            M = zeros(m,N);
            for n = 1:N
                M(:,n) = R(1,:).^or(1,n).*R(2,:).^or(2,n);%prod(R(:,j).^or(:,n));
            end
        end
        
        function [X1,X2] = GenerateFullCart2DGrid(~,res)
        % Function that generates a 2D full Cartesian grid.
        %
        % INPUT:    * res : (2,1) vector or scalar that defines resolution
        %
        % OUTPUT:   * X1 : matrix where represents the first dimension
        %           * X2 : matrix that represents the second definition
        % 
        % Note: N = nb of samples = prod(res)
        %
        % Matthieu Guerquin-Kern, Biomedical Imaging Group / EPF Lausanne,
        % 30-10-2009 (dd-mm-yyyy)
        
            if isscalar(res)
                res = res*[1,1];
            end
            
            [X2,X1] = meshgrid( -floor(res(2)/2):floor((res(2)-1)/2),...
                -floor(res(1)/2):floor((res(1)-1)/2) );%dk=1
        end
        
        function [coeff,nrmse,ser,maxerror,condi] = Fitting(~,M,sensdata,method)
        % Function that fits the parameters for a given linear model.
        %
        % INPUT:    * M:        matrix that maps the coefficients to the data
        %           * data:     data points to be fitted
        %           * method:   'fast' or 'accurate' (default)
        %
        % OUTPUT:   * s: (N,1) vector of fitted coefficients
        %           * nrmse: normalized root mean square error
        %           * ser: signal to error ratio of the approximation
        %           * maxerror: maximal error of approximation inside the support
        %           * condition number of the matrix to be inverted (gives an
        %               indication of the accuracy of the results)
        %
        % See: SensFitting
        %
        % Matthieu Guerquin-Kern, Biomedical Imaging Group / EPF Lausanne,
        % 31-01-2011 (dd-mm-yyyy)
        
            if nargin<3
                method = 'accurate'; % default method
            end
            
            % Compute the parameters
            switch method
                case 'accurate'
                    coeff = M\sensdata; % linear system to invert, M\s = pinv(M)*s = M^-1*s
                case 'fast'
                    H = M'*M;
                    coeff = M'*sensdata;
                    coeff = H\coeff;
                otherwise
                    error('fitting method must be either ''fast'' or ''accurate''.');
            end
            
            if nargout>1
                residue = sensdata-M*coeff;
                mse = residue'*residue/numel(residue);
                nrmse = sqrt(mse)/(max(abs(sensdata)-min(abs(sensdata))));
                ser = (sensdata'*sensdata)/mse/numel(residue);
                maxerror = max(abs(residue))/(max(abs(sensdata)-min(abs(sensdata))));
                switch method
                    case 'accurate'
                        condi = cond(M,2);
                    case 'fast'
                        condi = cond(H,2);
                end
            end
        end
    end
    
    methods (Access = public)
        
        function UIAxesInitialize(app)
            % Initialize UIAxes for zspec plot
            cla(app.UIAxes,'reset');
            title(app.UIAxes,"Simulation results");
            xlabel(app.UIAxes,"Offset [ppm]");
            ylabel(app.UIAxes,"Z");
            zlabel(app.UIAxes,"Z");
            app.UIAxes.FontSize = 18;
            app.UIAxes.XLim = [-10,10];
            app.UIAxes.YLim = [0,1];
            app.UIAxes.XTick = [-10,-5,0,5,10];
            app.UIAxes.XTickLabel = [-10,-5,0,5,10];
            app.UIAxes.YTick = [0.0,0.2,0.4,0.6,0.8,1.0];
            app.UIAxes.YTickLabel = [0.0,0.2,0.4,0.6,0.8,1.0];
            app.UIAxes.XDir = "reverse";
            xtickformat(app.UIAxes,"%.1f")
            ytickformat(app.UIAxes,"%.1f")
        end
                
        
        function pulse_cell = pulseReadFromUI(app)
            % Construct saturation pulses using UI settings
            % OUTPUT:
            %   pulseCell (stored as property): {pulse1, pulse2, pulse3,...}
            %       pulse: [power1,phase1,duration1;power2,phase2,duration2;...]
            %       power: in Hz, 0 for no pulse but delay
            %       phase: (0:x, pi/2:y, pi:-x, 3*pi/2:-y) 
            %       duration: in s 

            pulse1_dur = 1;
            my_sinc = @(x) fillmissing(sin(pi * x) ./ (pi * x),'constant',1);

            switch app.PulseshapeDropDown.Value
                case "CW"
                    pulse1_waveform = 1;

                case "Sinc" % 2 para. (alpha, N)
                    pulse1_points = app.RFsamplesEditField.Value;
                    alpha = app.Shapepara1EditField.Value; % 0.46 for Hamming window, 0.5 for Hanning window
                    N = max(round(app.Shapepara2EditField.Value),1); % half of the zero-crossing points, N = 1 for single-lobe sinc pulse
                    t_list = linspace(-1,1,pulse1_points);
                    pulse1_waveform = 1 * (1-alpha + alpha*cos(pi*t_list)) .* my_sinc(N*t_list);


                case "Gauss" % 1 para. (sigma)
                    pulse1_points = app.RFsamplesEditField.Value;
                    sigma = app.Shapepara1EditField.Value; % sigma = RFdur/7.434 for 60-dB attenuation
                    t_list = linspace(-pulse1_dur/2,pulse1_dur/2,pulse1_points);
                    pulse1_waveform = 1 * exp(-t_list.^2/2/sigma^2);

                case "Fermi" % 2 para. (ratio, a)
                    pulse1_points = app.RFsamplesEditField.Value;
                    ratio = app.Shapepara1EditField.Value; % defalut 16, ratio should >> 1, 
                    a = app.Shapepara2EditField.Value; % pulse1_dur/(2*ratio+13.81) for 60-dB attenuation, Fermi -> Hard pulse when a -> 0
                    t0 = a * ratio; % describe pulse width
                    t_list = linspace(-pulse1_dur/2,pulse1_dur/2,pulse1_points);
                    pulse1_waveform = 1./ ( 1 + exp( (abs(t_list)-t0)/a ) );
                case "Other"
                    importedRF2interpRF(app);
                    pulse1_waveform = 1 .* app.pulseWaveform; % loaded
            end
            app.pulseWaveform = pulse1_waveform(:);
            pulse_cell = pulseWaveform2pulseCell(app);
            app.pulseCell = pulse_cell;
 
        end
        
        
        function pools = poolsCheck(~,pools)
            % delete pool cell with zero T1/T2
            % INPUT:
            %   pools: size [Npools,1]
            % OUTPUT:
            %   pools: size [Npools_c,1], Npools_c <= Npools

            idx = 1;
            for loop = 1:length(pools)
                T1 = pools{idx}{2};
                T2 = pools{idx}{3};
                if T1*T2 < eps
                    pools(idx,:) = [];
                else
                    idx = idx + 1;
                end
            end
            
        end
        
        
        function loadPulseFromFile(app,RFfilepath)
            % load RF pulse from file,
            % INPUT:
            %   fullpath: absolute path of the RF file
            % OUTPUT:
            %   app.pulseCell:
            %   RFfilepath: set the UI value
            if ~exist('RFfilepath','var') || isempty(RFfilepath)
                [file,path] = uigetfile({'*.satpara;*.txt', 'supporting files (*.satpara,*.txt)';...
                    '*.satpara','CESTsimu files (*.satpara)';...
                    '*.txt','RF shape files (*.txt)'},...
                    'Select the pulse file');
                figure(app.UIFigure);
                RFfilepath = fullfile(path,file);
            end

            fid = fopen(RFfilepath,'r');
            if fid >= 0
                RFfilename = strsplit(RFfilepath,"\");
                textTable = [];
                while ~feof(fid)  
                    textTable = [textTable;string(fgetl(fid))];
                end

                % Saturation Info if .SATPARA file
                if contains(RFfilepath,'.satpara')
                    assignValueToUI(app, app.PulseshapeDropDown, textTable, 'Shape', 'string', 'CW');
                    assignValueToUI(app, app.PulsenumberEditField, textTable, 'Pulse repeat', 'double', 1);
                    assignValueToUI(app, app.PulsedurationEditField, textTable, 'Pulse width (ms)', 'double', 2000);
                    assignValueToUI(app, app.PulsedelayEditField, textTable, 'Pulse gap (ms)', 'double', 0);
                end

                app.RFsamplesEditField.Enable = "on";
                switch app.PulseshapeDropDown.Value
                    case "CW"
                        app.RFsamplesEditField.Enable = "off";
                        app.SelectedpulseshapeEditField.Value = "CW";
                    case "Sinc"
                        assignValueToUI(app, app.Shapepara1EditField, textTable, 'Shape para1', 'double', 0.46);
                        assignValueToUI(app, app.Shapepara2EditField, textTable, 'Shape para2', 'double', 1);
                        app.Shapepara1EditField.Enable = 'on';
                        app.Shapepara2EditField.Enable = 'on';
                        app.SelectedpulseshapeEditField.Value = "Sinc";
                        app.Shapepara1EditFieldLabel.Text = '$\alpha$';
                        app.Shapepara2EditFieldLabel.Text = '$N$';
                    case "Gauss"
                        assignValueToUI(app, app.Shapepara1EditField, textTable, 'Shape para1', 'double', 1/7.434);
                        app.Shapepara1EditField.Enable = 'on';
                        app.SelectedpulseshapeEditField.Value = "Gauss";
                        app.Shapepara1EditFieldLabel.Text = '$\sigma$';
                    case "Fermi"
                        assignValueToUI(app, app.Shapepara1EditField, textTable, 'Shape para1', 'double', 16);
                        assignValueToUI(app, app.Shapepara2EditField, textTable, 'Shape para2', 'double', 1/(2*16+13.81));
                        app.Shapepara1EditField.Enable = 'on';
                        app.Shapepara2EditField.Enable = 'on';
                        app.SelectedpulseshapeEditField.Value = "Fermi";
                        app.Shapepara1EditFieldLabel.Text = '$t_0 / a$';
                        app.Shapepara2EditFieldLabel.Text = '$a$';
                    case "Other"
                        % find shaped RF waveform
                        idxList = find(contains(textTable,'#')==1);
                        if isempty(idxList)
                            idxStart = 1;
                        else
                            idxStart = idxList(end) + 1;
                        end
        
                        % load pulse
                        pulseStr = textTable(idxStart : end);
                        pulsewaveform = zeros(length(pulseStr),1);
                        for idx = 1:length(pulseStr)
                            strTemp = str2num(pulseStr(idx));
                            pulsewaveform(idx,:) = strTemp(end);
                        end
                        
                        % variable assignment
                        app.pulseImportWaveform = pulsewaveform./max(abs(pulsewaveform(:)));
                        app.RFsamplesEditField.Value = length(pulsewaveform);
                        app.pulseWaveform = app.pulseImportWaveform;
                        app.pulseCell = pulseWaveform2pulseCell(app);

                        app.LoadfromEditField_pulse.Value = RFfilename{end};
                        app.LoadfromEditField_pulse.Enable = "on";
                        app.SelectedpulseshapeEditField.Value = "Other";
                end

                appendOutputLog(app, ": successfully import pulse from " + RFfilename{end});
                fclose(fid);
            else
                appendOutputLog(app, ": pulse waveform import fails")
                app.LoadfromEditField_pulse.Enable = "off";
                app.PulseshapeDropDown.Value = "CW";
                app.RFsamplesEditField.Enable = "off";
                app.SelectedpulseshapeEditField.Value = "CW";
            end


        end

        function loadFreqOffsFromFile(app,freqoffsfilepath)
            % load frequency offset from file,
            % INPUT:
            %   fullpath: absolute path of the RF file
            % OUTPUT:
            %   app.freqoffs_global:
            %   freqoffsfilepath: set the UI value

            if ~exist('freqoffsfilepath','var') || isempty(freqoffsfilepath)
                [file,path] = uigetfile({'*.satpara;*.seq;*.txt', 'supporting files (*.satpara,*.seq,*.txt)';...
                    '*.satpara','CESTsimu files (*.satpara)';...
                    '*.seq','pulseq-cest files (*.seq)';...
                    '*.txt','text files (*.txt)'},...
                    'Select the frequency offset file');
                figure(app.UIFigure);
                freqoffsfilepath = fullfile(path,file);
            end

            fid = fopen(freqoffsfilepath,'r');
            if fid >= 0
                textTable = [];
                while ~feof(fid)  
                    textTable = [textTable;string(fgetl(fid))];
                end

                filepostfix = strsplit(string(freqoffsfilepath),".");
                switch filepostfix(end)
                    case "satpara"
                        idxStart = find(contains(textTable,'#')==1);
                        freqoffsMode = strsplit(regexprep(textTable(idxStart(2) + 1),'\s+(:)\s+','$1'),':'); 
                        switch freqoffsMode(end)
                            case "Set offs."
                                app.FreqoffsmodeDropDown.Value = "Set offs.";
                                app.LoadfromEditField_freqoffs.Enable = "off";
                                app.FreqoffsfromEditField.Enable = "on";
                                app.FreqoffstoEditField.Enable = "on";
                                app.FreqoffsstepEditField.Enable = "on";    

                                assignValueToUI(app, app.FreqoffsM0EditField, textTable, 'Freq. offset M0', 'double', -300);
                                assignValueToUI(app, app.FreqoffsfromEditField, textTable, 'Freq. offset from', 'double', -10);
                                assignValueToUI(app, app.FreqoffstoEditField, textTable, 'Freq. offset to', 'double', 10);
                                assignValueToUI(app, app.FreqoffsstepEditField, textTable, 'Freq. offset step', 'double', 0.25);
                                app.freqoffs_global = app.FreqoffsfromEditField.Value : app.FreqoffsstepEditField.Value : app.FreqoffstoEditField.Value;
                                app.FreqoffsppmSlider.Limits = [min(app.freqoffs_global,[],'all'),max(app.freqoffs_global,[],'all')];
                            case "Load offs."
                                app.FreqoffsmodeDropDown.Value = "Load offs.";
                                app.LoadfromEditField_freqoffs.Enable = "on";
                                app.FreqoffsfromEditField.Enable = "off";
                                app.FreqoffstoEditField.Enable = "off";
                                app.FreqoffsstepEditField.Enable = "off";

                                Freqoffsfilename = strsplit(freqoffsfilepath,"\");
                                app.LoadfromEditField_freqoffs.Value = Freqoffsfilename{end};
                                assignValueToUI(app, app.FreqoffsM0EditField, textTable, 'Freq. offset M0', 'double', -300);
                                
                                freqoffsList_idx = idxStart(2)+4 : 1 : idxStart(3)-2;
                                strTemp = textTable(freqoffsList_idx); app.freqoffs_global = str2num(join(strTemp,','));    
                                app.FreqoffsppmSlider.Limits = [min(app.freqoffs_global,[],'all'),max(app.freqoffs_global,[],'all')];
                        end
                    case "seq"
                        app.FreqoffsmodeDropDown.Value = "Load offs.";
                        app.LoadfromEditField_freqoffs.Enable = "on";
                        app.FreqoffsfromEditField.Enable = "off";
                        app.FreqoffstoEditField.Enable = "off";
                        app.FreqoffsstepEditField.Enable = "off";

                        strTemp = strsplit(regexprep( textTable(contains(textTable,'M0_offset')==1), '\s*$',"")); app.FreqoffsM0EditField.Value = str2double(strTemp(end));
                        strTemp = strsplit(regexprep( textTable(contains(textTable,'offsets_ppm')==1), '\s*$',"")); app.freqoffs_global = str2num( join(strTemp(3:end)) );
                        app.FreqoffsppmSlider.Limits = [min(app.freqoffs_global,[],'all'),max(app.freqoffs_global,[],'all')];
                        
                        Freqoffsfilename = strsplit(freqoffsfilepath,"\");
                        app.LoadfromEditField_freqoffs.Value = Freqoffsfilename{end};
                    case "txt"
                        app.FreqoffsmodeDropDown.Value = "Load offs.";
                        app.LoadfromEditField_freqoffs.Enable = "on";
                        app.FreqoffsfromEditField.Enable = "off";
                        app.FreqoffstoEditField.Enable = "off";
                        app.FreqoffsstepEditField.Enable = "off";

                        Freqoffsfilename = strsplit(freqoffsfilepath,"\");
                        app.LoadfromEditField_freqoffs.Value = Freqoffsfilename{end};

                        freqArr = str2num(join(strrep(textTable,',','') , ','));
                        app.FreqoffsM0EditField.Value = freqArr(1);
                        app.freqoffs_global = freqArr(2:end);    
                        app.FreqoffsppmSlider.Limits = [min(app.freqoffs_global,[],'all'),max(app.freqoffs_global,[],'all')];
                end
                fclose(fid);
            else % loading fail
                app.FreqoffsmodeDropDown.Value = "Set offs.";
                app.LoadfromEditField_freqoffs.Enable = "off";
                app.FreqoffsfromEditField.Enable = "on";
                app.FreqoffstoEditField.Enable = "on";
                app.FreqoffsstepEditField.Enable = "on";    
            end


        end
        
        function pulse_cell = pulseWaveform2pulseCell(app)
            % construct pulse cell using app.pulseWaveform and UI params
            % sequence: [pulse1, pulse2] -> [pulse1, pulse2] -> ... -> tpost -> acquisition
            %           \________________________________________/
            %                    repeat pulse_repeat times
            % OUTPUT:
            %   pulseCell (stored as property): {pulse1, pulse2, pulse3,...}
            %       pulse: [power1,phase1,duration1;power2,phase2,duration2;...]
            %       power: in Hz, 0 for no pulse but delay
            %       phase: (0:x, pi/2:y, pi:-x, 3*pi/2:-y) 
            %       duration: in s 
            
            % (1) construct pulse block
            pulse1_waveform = app.pulseWaveform;
            app.B1maxEditField.Value = B1rms2B1max(app, app.B1rmsEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
            pulse1_pwr = app.B1maxEditField.Value; % in uT
            pulse1_dur = app.PulsedurationEditField.Value / 1000; % pulse duration in s
            pulse1_phase = 0; % angle in X-Y plane, [0, pi/2, pi, 3pi/2] for [X, Y, -X, -Y] direction
            pulse1 = [reshape(pulse1_waveform,[],1)*pulse1_pwr*app.gamma_hz,...
                              pulse1_phase*ones( size(pulse1_waveform(:)) ),...
                              pulse1_dur/length(pulse1_waveform(:))*ones( size(pulse1_waveform(:)) )];

            pulse2_tmix = app.PulsedelayEditField.Value / 1000; % pulse mixing time in s
            pulse2 = [0, 0, pulse2_tmix];
            pulse_block = {pulse1, pulse2};
            
            % (2) repeat pulse block
            pulse_block_len = length(pulse_block);
            pulse_repeat = app.PulsenumberEditField.Value; % repeat number of pulse cell
            pulse_phasecyc = str2num(regexprep(app.PulsephasecycEditField.Value,'[()]',''))/180*pi; % in radian
            if isempty(pulse_phasecyc)
                pulse_phasecyc = 0;
            end
            pulse_cell = cell(1,pulse_block_len*pulse_repeat);
            for idx = 1:pulse_block_len:size(pulse_cell,2)
                pulse1Temp = pulse1;
                idx_phcyc = mod( (idx-1)/pulse_block_len, length(pulse_phasecyc)) + 1;
                pulse1Temp(:,2) = mod( pulse_phasecyc(idx_phcyc), 2*pi ) *ones(size(pulse1,1),1);
                pulse_cell(1,idx:idx+pulse_block_len-1) = {pulse1Temp, pulse2};
            end
            pulse_cell(end) = []; % ends with pulse (no last delay)
            
        end
        
        function loadSaturationfromCESTsimu(app, textTable, filedir)
            % load saturation info. from CESTsimu file (.satpara)
            % INPUT:
            %   textTable: text of the imported file
            %   filedir:    dir of the imported file
            % OUTPUT:
            %   setting UIpara

            % B0 info
            assignValueToUI(app, app.B0EditField, textTable, 'B0 (T)', 'double', 3);
            assignValueToUI(app, app.B0inhomEditField, textTable, 'B0 inhomogeneity  (ppm)', 'double', 0);

            % Frequency offsets
            [~,freqoffsMode] = assignValueToUI(app, app.FreqoffsmodeDropDown, textTable, 'Freq. offset mod', 'string', 'Set offs.');
            switch freqoffsMode
                case "Set offs."
                    assignValueToUI(app, app.FreqoffsM0EditField, textTable, 'Freq. offset M0', 'double', -300);
                    assignValueToUI(app, app.FreqoffsfromEditField, textTable, 'Freq. offset from', 'double', -10);
                    assignValueToUI(app, app.FreqoffstoEditField, textTable, 'Freq. offset to', 'double', 10);
                    assignValueToUI(app, app.FreqoffsstepEditField, textTable, 'Freq. offset step', 'double', 0.25);
            
                    app.freqoffs_global = app.FreqoffsfromEditField.Value : ...
                                      app.FreqoffsstepEditField.Value : ...
                                      app.FreqoffstoEditField.Value;

                    app.LoadfromEditField_freqoffs.Enable = "off";
                    app.FreqoffsfromEditField.Enable = "on";
                    app.FreqoffstoEditField.Enable = "on";
                    app.FreqoffsstepEditField.Enable = "on";
                case "Load offs."
                    loadFreqOffsFromFile(app,filedir);

                    app.LoadfromEditField_freqoffs.Enable = "on";
                    app.FreqoffsfromEditField.Enable = "off";
                    app.FreqoffstoEditField.Enable = "off";
                    app.FreqoffsstepEditField.Enable = "off";
            end

            % Pulse info
            assignValueToUI(app, app.PostpulsedelayEditField, textTable, 'Post pulse delay(ms)', 'double', 6.5);
            assignValueToUI(app, app.PulseshapeDropDown, textTable, 'Shape type', 'string', 'CW');
            app.Shapepara1EditField.Enable = 'off';
            app.Shapepara2EditField.Enable = 'off';
            switch app.PulseshapeDropDown.Value
                case "CW"
                    app.SelectedpulseshapeEditField.Value = "CW";
                case "Sinc"
                    assignValueToUI(app, app.Shapepara1EditField, textTable, 'Shape para1', 'double', 0.46);
                    assignValueToUI(app, app.Shapepara2EditField, textTable, 'Shape para2', 'double', 1);
                    app.Shapepara1EditField.Enable = 'on';
                    app.Shapepara2EditField.Enable = 'on';
                    app.SelectedpulseshapeEditField.Value = "Sinc";
                    app.Shapepara1EditFieldLabel.Text = '$\alpha$';
                    app.Shapepara2EditFieldLabel.Text = '$N$';
                case "Gauss"
                    assignValueToUI(app, app.Shapepara1EditField, textTable, 'Shape para1', 'double', 1/7.434);
                    app.Shapepara1EditField.Enable = 'on';
                    app.SelectedpulseshapeEditField.Value = "Gauss";
                    app.Shapepara1EditFieldLabel.Text = '$\sigma$';
                case "Fermi"
                    assignValueToUI(app, app.Shapepara1EditField, textTable, 'Shape para1', 'double', 16);
                    assignValueToUI(app, app.Shapepara2EditField, textTable, 'Shape para2', 'double', 1/(2*16+13.81));
                    app.Shapepara1EditField.Enable = 'on';
                    app.Shapepara2EditField.Enable = 'on';
                    app.SelectedpulseshapeEditField.Value = "Fermi";
                    app.Shapepara1EditFieldLabel.Text = '$t_0 / a$';
                    app.Shapepara2EditFieldLabel.Text = '$a$';
                case "Other"
                    app.SelectedpulseshapeEditField.Value = "Other";
            end
            assignValueToUI(app, app.B1maxEditField, textTable, 'B1_max (uT)', 'double', 0.8);
            assignValueToUI(app, app.B1rmsEditField, textTable, 'B1_rms (uT)', 'double', 0.8);
            assignValueToUI(app, app.B1inhomEditField, textTable, 'B1 inhom.', 'double', 1);
            assignValueToUI(app, app.PulsedurationEditField, textTable, 'Pulse width (ms)', 'double', 2000);
            assignValueToUI(app, app.PulsedelayEditField, textTable, 'Pulse gap (ms)', 'double', 0);
            assignValueToUI(app, app.PulsenumberEditField, textTable, 'Pulse repeat', 'double', 1);
            assignValueToUI(app, app.PulsephasecycEditField, textTable, 'Pulse phase cycle (deg)', 'string', '0,0,0,0');
            assignValueToUI(app, app.CompaccumphaseCheckBox, textTable, 'Compensate accum. phase', 'double', 1);
            assignValueToUI(app, app.RecoverytimeEditField, textTable, 'Recovery time', 'double', 3);
            assignValueToUI(app, app.ResetinitmagCheckBox, textTable, 'Reset init. mag.', 'double', 1);
            if app.ResetinitmagCheckBox.Value == 1
                app.RecoverytimeEditField.Enable = 'off';
            else
                app.RecoverytimeEditField.Enable = 'on';
            end
        end
        
        function loadSaturationfromPulseq(app, textTable, filedir)
            % load saturation info. from pulseq file (.seq)
            %   no RF shape is imported from .seq
            % INPUT:
            %   textTable: text of the imported file
            %   filedir:    dir of the imported file
            % OUTPUT:
            %   setting UIpara

            %% default
            % options that cannot be read from SEQ
            app.CompaccumphaseCheckBox.Value = 1;
            app.ResetinitmagCheckBox.Value = 1;
            app.RecoverytimeEditField.Enable = 'off';
            app.PostpulsedelayEditField.Value = 6.5;

            %% [RF shape]
            idxStart = find(contains(textTable,'[RF]')==1);
            strTemp = strsplit(textTable(idxStart+1));
            % id amplitude mag_id phase_id delay freq phase
            mag_id = strTemp(3);
            idxStart = find(contains(textTable,"shape_id "+ mag_id)==1);
            strTemp = strsplit(textTable(idxStart+1));
            num_samples = str2double(strTemp(2));

            RFShapeArr = [];
            idx = idxStart + 2;
            strTemp = textTable(idx);
            while ~strcmp(strTemp,"") && isempty(regexp(strTemp,'^\s*$', 'once'))
                RFShapeArr = [RFShapeArr;str2double(strTemp)];
                idx = idx + 1;
                strTemp = textTable(idx);
                if idx >= size(textTable,1)
                    break;
                end
            end

            if isempty(find( (round(RFShapeArr)-RFShapeArr~=0) == 1, 1))
                % no decimal number, hard pulse
                app.PulseshapeDropDown.Value = "CW";
                app.pulseWaveform = 1;
                app.pulseCell = pulseWaveform2pulseCell(app);
                app.LoadfromEditField_pulse.Enable = "off";
            elseif length(RFShapeArr) < num_samples
                % uncompressed, assuming multiple CW pulse
                app.PulseshapeDropDown.Value = "CW";
                app.pulseWaveform = 1;
                app.pulseCell = pulseWaveform2pulseCell(app);
                app.LoadfromEditField_pulse.Enable = "off";
                % idxrepeat = find(RFShapeArr>1);
                % for idx = 1:length(idxrepeat)
                %     repeatvalue = RFShapeArr(idxrepeat(idx)-1);
                %     repeattimes = round(RFShapeArr(idxrepeat(idx)));
                %     RFShapeArr = [RFShapeArr(1:idxrepeat(idx)-1);...
                %                   repmat(repeatvalue,repeattimes,1);...
                %                   RFShapeArr(idxrepeat(idx)+1:end)];
                % end
                % % integral
                % pulsewaveform = cumsum(RFShapeArr,1);
            else
                % shaped pulse
                app.PulseshapeDropDown.Value = "Other";
                pulsewaveform = RFShapeArr;

                % load pulse waveform
                app.pulseImportWaveform = pulsewaveform;
                app.RFsamplesEditField.Value = length(pulsewaveform);
                app.pulseWaveform = pulsewaveform;
                app.pulseCell = pulseWaveform2pulseCell(app);
    
                RFfilename = strsplit(filedir,"\");
                app.LoadfromEditField_pulse.Value = RFfilename{end};
                app.LoadfromEditField_pulse.Enable = "on";
            end

            %% [DEFINITIONS]
            idxStart = find(contains(textTable,'[DEFINITIONS]')==1);
            satText = [];
            idx = idxStart;
            strTemp = textTable(idx);
            while ~strcmp(strTemp,"") && isempty(regexp(strTemp,'^\s*$', 'once'))
                satText = [satText;strTemp];
                idx = idx + 1;
                strTemp = textTable(idx);
                if idx >= size(textTable,1)
                    break;
                end
            end

            % B0 info
            strTemp = strsplit( regexprep( satText(contains(satText,'B0')==1), '\s*$',"") );
            app.B0EditField.Value = str2double(strTemp(end));
            app.B0inhomEditField.Value = 0; % not applicable in .seq
            
            % Frequency offsets
            app.FreqoffsmodeDropDown.Value = "Set offs.";
            loadFreqOffsFromFile(app,filedir);

            % Pulse info
            strTemp = strsplit(regexprep( satText(contains(satText,'tp')==1), '\s*$',"")); app.PulsedurationEditField.Value = str2double(strTemp(end))*1000;
            strTemp = regexprep( satText(contains(satText,'td')==1), '\s*$',""); 
            if isempty(strTemp)
                app.PulsedelayEditField.Value = 0;
            else
                strTemp = strsplit(strTemp);
                app.PulsedelayEditField.Value = str2double(strTemp(end))*1000;
            end
            strTemp = strsplit(regexprep( satText(contains(satText,'n_pulses')==1), '\s*$',"")); app.PulsenumberEditField.Value = str2double(strTemp(end));  
            
            % Trec
            strTemp = satText(contains(satText,'Trec')==1); % two TRs, only use the first one
            strTemp = strsplit(regexprep( strTemp(1), '\s*$',"")); app.RecoverytimeEditField.Value = str2double(strTemp(end));  

            % load order: B1rms > B1pa > B1cwpe
            strTemp = regexprep( satText(contains(satText,'B1rms')==1), '\s*$',"");
            if ~isempty(strTemp)
                strTemp = strsplit(strTemp);
                app.B1rmsEditField.Value = round(str2double(strTemp(end)),8);
                app.B1maxEditField.Value = B1rms2B1max(app, app.B1rmsEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
            elseif(~isempty(regexprep( satText(contains(satText,'B1pa')==1), '\s*$',"")))
                strTemp = regexprep( satText(contains(satText,'B1pa')==1), '\s*$',"");
                strTemp = strsplit(strTemp);
                app.B1maxEditField.Value = round(str2double(strTemp(end)),8);
                app.B1rmsEditField.Value = B1max2B1rms(app, app.B1maxEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
            elseif(~isempty(regexprep( satText(contains(satText,'B1cwpe')==1), '\s*$',"")))
                strTemp = regexprep( satText(contains(satText,'B1cwpe')==1), '\s*$',"");
                strTemp = strsplit(strTemp);
                app.B1maxEditField.Value = round(str2double(strTemp(end)),8);
                app.B1rmsEditField.Value = B1max2B1rms(app, app.B1maxEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
            end

            %% done
            strTemp = strsplit(filedir,'\');
            filename = strTemp(end);
            appendOutputLog(app, ": successfully import settings from " + filename);
            
            
        end
        
        function B1max = B1rms2B1max(app, B1rms, tp, td)
            % convert B1 rms (root mean square) to B1 maximum value
            % INPUT:
            %   B1rms: uT
            %   app.pulseWaveform: waveform of B1, dimensionless
            %   tp: time of pulse, ms
            %   td: time of delay, i.e. gap between pulses, ms
            % OUTPUT:
            %   B1max: uT

            pulseShape = app.pulseWaveform;
            pulseShape_uni = pulseShape./max(abs(pulseShape(:)));
            Nt = length(pulseShape);
            dt = tp/Nt;
            B1max = B1rms / sqrt( sum( pulseShape_uni.^2 .* dt) / (tp+td) ); % uT
            B1max = round(B1max,4);
        end
        
        function B1rms = B1max2B1rms(app, B1max, tp, td)
            % convert B1 maximum value to B1 rms (root mean square)
            % INPUT:
            %   B1max: uT
            %   app.pulseWaveform: waveform of B1, dimensionless
            %   tp: time of pulse, ms
            %   td: time of delay, i.e. gap between pulses, ms
            % OUTPUT:
            %   B1rms: uT
            
            pulseShape = app.pulseWaveform;
            pulseShape_uni = pulseShape./max(abs(pulseShape(:)));
            Nt = length(pulseShape);
            dt = tp/Nt;
            B1rms = B1max * sqrt( sum( pulseShape_uni.^2 .* dt) / (tp+td) ); % uT
            B1rms = round(B1rms,4);
        end
        
        
        function iniUIExcPool(app)
            % initialize UI exchange pools settings to one-pool model
            app.f_Water.Value = 111; app.k_Water.Value = 1; app.w_Water.Value = 0; app.T1_Water.Value = 1; app.T2_Water.Value = 0.04;
            app.f_Amide.Value = 0; app.k_Amide.Value = 0; app.w_Amide.Value = 0; app.T1_Amide.Value = 0; app.T2_Amide.Value = 0;
            app.f_NOE.Value = 0; app.k_NOE.Value = 0; app.w_NOE.Value = 0; app.T1_NOE.Value = 0; app.T2_NOE.Value = 0;
            app.f_MT.Value = 0; app.k_MT.Value = 0; app.w_MT.Value = 0; app.T1_MT.Value = 0; app.T2_MT.Value =0;
            app.f_Guan.Value = 0; app.k_Guan.Value = 0; app.w_Guan.Value = 0; app.T1_Guan.Value = 0; app.T2_Guan.Value = 0;
            app.f_Other1.Value = 0; app.k_Other1.Value = 0; app.w_Other1.Value = 0; app.T1_Other1.Value = 0; app.T2_Other1.Value = 0;
            app.f_Other2.Value = 0; app.k_Other2.Value = 0; app.w_Other2.Value = 0; app.T1_Other2.Value = 0; app.T2_Other2.Value = 0;
            app.f_Other3.Value = 0; app.k_Other3.Value = 0; app.w_Other3.Value = 0; app.T1_Other3.Value = 0; app.T2_Other3.Value = 0;
        end
        
        function appendOutputLog(app, str)
            % append str to current outputlog
            app.OutputLogTextArea.Value = [app.OutputLogTextArea.Value;...
                            string(datetime('now','Format','HH:mm:ss')) + str];
            scroll(app.OutputLogTextArea,'bottom');
        end
        
        function [CESTmap_temp, MTRmap_temp,magn_P_rec,magn_N_rec,flag] = zmap_1pool_1offs(app,offs,pools,B1maptemp,B0maptemp,mask,magn_P_ini,magn_N_ini)
            % calculate noisy Zmap and MTRmap of a pool setting and a freq. offs.
            % INPUT:
            %   offs: scalar value
            %   magn_P_rec: [Nx,Ny,nPool*3], M vector of last TR delay, at positive offset
            %   magn_N_rec: [Nx,Ny,nPool*3], M vector last TR delay, at negative offset
            % OUTPUT:
            %   CESTmap_temp: [Nx,Ny]
            %   MTRmap_temp: [Nx,Ny]
            %   magn_P_rec: [Nx,Ny,nPool*3], M vector of this TR delay, at positive offset
            %   magn_N_rec: [Nx,Ny,nPool*3], M vector of this TR delay, at negative offset

            flag = 0;
            % initial M when considering TR delay
            nPool = length(pools);
            
            % saturation settings
            b0 = app.B0EditField.Value;
            pulse_cell = app.pulseCell;
            if isempty(pulse_cell)
                pulseReadFromUI(app);
                pulse_cell = app.pulseCell;
            end
            pulse_tpost = app.PostpulsedelayEditField.Value / 1000; %s 

            % calculate zmap pixel-vise
            CESTmap_temp = zeros(size(mask));
            MTRmap_temp = zeros(size(mask));
            magn_P_rec = zeros([size(mask),nPool*3]);
            magn_N_rec = zeros([size(mask),nPool*3]);
            [idx_roi_list,idy_roi_list] = find(mask~=0);

            Trec = app.RecoverytimeEditField.Value; % s
            for idpixel = 1:length(idx_roi_list)
                idx = idx_roi_list(idpixel);
                idy = idy_roi_list(idpixel);
                if app.stopFlag == true
                    flag = 1;
                    return;
                end

                % B1/B0 inhomo setting
                % offs_temp = offs + B0maptemp(idx);
                offs_temp = offs;
                poolsTemp = cellfun(@(x) {x{1:4}, ...
                    x{5}-B0maptemp(idx,idy), ...
                    x{6}}, pools, 'UniformOutput', false);
                pulse_celltemp = cellfun(@(x) x .* [B1maptemp(idx,idy),1,1],pulse_cell,'UniformOutput' ,false);

                % add RF accumulated phase
                pulsetemp = addRFAccumPhase(app,pulse_celltemp,offs_temp);
                
                % calculation
                if app.ResetinitmagCheckBox.Value == 1
                    magn_P = bmesolver(app,b0, app.gamma_hz, poolsTemp, pulsetemp, pulse_tpost, offs_temp, 0);
                    magn_N = bmesolver(app,b0, app.gamma_hz, poolsTemp, pulsetemp, pulse_tpost, -offs_temp, 0);
                else
                    % consider TR delay
                    if ~exist('magn_P_ini','var') || isempty(magn_P_ini)
                        magn_P = bmesolver(app,b0, app.gamma_hz, poolsTemp, pulsetemp, pulse_tpost, offs_temp, 0);
                        magn_N = bmesolver(app,b0, app.gamma_hz, poolsTemp, pulsetemp, pulse_tpost, -offs_temp, 0);
                    else
                        magn_P = bmesolver(app,b0, app.gamma_hz, poolsTemp, pulsetemp, pulse_tpost, offs_temp, magn_P_ini(idx,idy,:));
                        magn_N = bmesolver(app,b0, app.gamma_hz, poolsTemp, pulsetemp, pulse_tpost, -offs_temp, magn_N_ini(idx,idy,:));
                    end
                    magn_P(1:end-length(poolsTemp))=0;
                    magn_N(1:end-length(poolsTemp))=0;
                    magn_P = bmesolver(app, b0, app.gamma_hz, poolsTemp, [], Trec, offs_temp, magn_P);
                    magn_N = bmesolver(app, b0, app.gamma_hz, poolsTemp, [], Trec, offs_temp, magn_N);
                end
                z_P = magn_P(length(poolsTemp)*2+1);
                z_N = magn_N(length(poolsTemp)*2+1);

                % fill in the map
                CESTmap_temp(idx,idy) = z_P;
                MTRmap_temp(idx,idy) = z_N - z_P;
                magn_P_rec(idx,idy,:) = magn_P(:);
                magn_N_rec(idx,idy,:) = magn_N(:);
            end

        end
        
        
        function updateLogRealtime(app, str)
            app.OutputLogTextArea.Value = str;
            scroll(app.OutputLogTextArea,'bottom');
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'CESTsimu';
            UIAxesInitialize(app);
            app.OutputLogTextArea.Value = "CESTsimu-v" + app.appVersion;

            app.PulseshapeDropDown.Value = "CW";
            app.LoadfromEditField_pulse.Enable = "off";

            app.FreqoffsmodeDropDown.Value = "Set offs.";
            app.LoadfromEditField_freqoffs.Enable = "off";
            app.FreqoffsfromEditField.Enable = "on";
            app.FreqoffstoEditField.Enable = "on";
            app.FreqoffsstepEditField.Enable = "on";
            app.B1maxEditField.Value = B1rms2B1max(app, app.B1rmsEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if app.ZspecBatchShowStatus == 1
                UIAxesInitialize(app);
                app.PlotButtonHitNumber = 0;
                app.ZspecBatchShowStatus = 0;
            end
            app.stopFlag = false;

            % Prompt user for a legend label
            prompt = {'Enter legend label:'}; % Prompt for user input
            title = 'Legend Input'; % Title for the dialog box
            dims = [2 45]; % Dimensions of input field
            definput = {'Curve Label'}; % Default text in input field
            legendLabel = inputdlg(prompt, title, dims, definput);
            figure(app.UIFigure);
        
            % Check if user canceled the input dialog
            if isempty(legendLabel)
                return; % Do nothing if user canceled the dialog
            end
            
            app.PlotButtonHitNumber = app.PlotButtonHitNumber+1;
            offs_MT = app.FreqoffsM0EditField.Value;
            offs_show = reshape(app.freqoffs_global,1,[]);
            offs = reshape([offs_MT,offs_show],[],1);

            pools = poolsRead(app);
            [zspec,flag] = bmesimulate(app,offs,pools,1);
            if flag == 1
                appendOutputLog(app, ": calculation terminated");
                app.stopFlag = false;
                return;
            end

            %% add noise
            if app.GaussNoiseOnCheckBox.Value == 1
                sigma = app.sigmaEditField.Value;
                zspec = zspec + sigma*randn(size(zspec));
            end
            app.ZspecHoldOnCell{app.PlotButtonHitNumber} = [offs,zspec];

            %% Plot
            app.UIAxes.XLim = [min(app.freqoffs_global,[],'all'), max(app.freqoffs_global,[],'all')];
            app.UIAxes.XTickMode = "auto";
            app.UIAxes.YLim = [min(zspec,[],'all'), max(zspec,[],'all')];
            app.UIAxes.YTickMode = "auto";
            hold(app.UIAxes,"on");
            [x,I] = sort(offs(2:end),'ascend');
            y = zspec(2:end)/zspec(1); y = y(I);
            plot(app.UIAxes, x, y,'DisplayName',legendLabel{1},'LineWidth',1);
            legend(app.UIAxes, 'show');

            % Adjust legend font size based on the number of entries
            adjustLegendFontSize(app);
        end

        % Value changed function: FreqoffsmodeDropDown
        function FreqoffsModeDropDownValueChanged(app, event)
            value = app.FreqoffsmodeDropDown.Value;
            if strcmp(value,"Set offs.")
                app.LoadfromEditField_freqoffs.Enable = "off";
                app.FreqoffsfromEditField.Enable = "on";
                app.FreqoffstoEditField.Enable = "on";
                app.FreqoffsstepEditField.Enable = "on";
                app.freqoffs_global = app.FreqoffsfromEditField.Value : ...
                                      app.FreqoffsstepEditField.Value : ...
                                      app.FreqoffstoEditField.Value;
                app.FreqoffsppmSlider.Limits = [min(app.freqoffs_global,[],'all'),max(app.freqoffs_global,[],'all')];

            else
                loadFreqOffsFromFile(app);
            end
        end

        % Button pushed function: ClearplotButton
        function ClearplotButtonPushed(app, event)
            UIAxesInitialize(app)
            app.ZspecHoldOnCell = [];
            app.PlotButtonHitNumber = 0;
            app.ZspecBatchShowStatus = 0;
            app.OutputLogTextArea.Value = "CESTsimu-v" + app.appVersion;
        end

        % Value changed function: FreqoffsppmSlider
        function FreqoffsppmSliderValueChanged(app, event)
            ex_offs_temp = app.FreqoffsppmSlider.Value;
            [~,ind_offs] = min(abs(app.freqoffs_global - ex_offs_temp)); % nearest offs
            ex_offs = app.freqoffs_global(ind_offs);

            app.FreqoffsppmSlider.Value = ex_offs;
            app.FreqOffs_precise.Value = ex_offs;

            Phantom2DPlot(app,ind_offs)
            zspec1DPlot_joint(app, ex_offs)
        end

        % Button pushed function: ShowButton_CEST, ShowButton_MTR
        function ShowButton_phantomPushed(app, event)
            % Locate freq. offs. index
            ex_offs = app.FreqoffsppmSlider.Value;
            ind_offs = find(app.freqoffs_global==ex_offs);

            % Plot
            Phantom2DPlot(app,ind_offs);
            zspec1DPlot_joint(app, ex_offs)
        end

        % Button pushed function: SaveparamButton
        function SaveExchangeParamButtonPushed(app, event)
            % save exchange settings as .excpara file
            
            % (1) load pools setting from UI
            % %        {name,            t1 [s],   t2 [s],    exch rate [Hz],  dw [ppm],    fraction (0~1)}
            % water  = {'water',        1.0,      0.04       1,               0,           1};
            % amide  = {'amide',        1.0,      0.1        50,              3.5,         0.0009009};
            % noe    = {'noe',          1.3,      0.005,     20,              -3.5,        0.0045};
            % mt     = {'mt',           1.0,      4.0e-05,   30,              -2.5,        0.1351};
            % guanid = {'guanidine',    1.0,      0.1,       1000,            2.0,         0.0009009};
            % pools = {water; mt; amide; guanid; noe};
            pools = poolsRead(app);
            for i = 1:numel(pools)
                pools{i}{6} = pools{i}{6}*111; % change concentration unit to [M]
            end

            % (2) transform into strings
            pools_names = strings(length(pools),1);
            paras_names = ["T1 [s]", "T2 [s]", "Exch Rate [Hz]", "dw [ppm]", "concentration [M]"];
            paras = zeros(length(pools),length(pools{1})-1);
            for poolIdx = 1:length(pools)
                pools_names(poolIdx) = pools{poolIdx}{1};
                for termIdx = 2:length(pools{1})
                    paras(poolIdx,termIdx-1) = pools{poolIdx}{termIdx};
                end
            end
            paras_value = string(num2str(paras));
            paras_value = regexprep(regexprep(paras_value,'(\S)(\s+)','$1,'),'\s+',''); % replace blank with comma
            OutputTable = [join(['Pool',paras_names],',');pools_names+','+paras_value];

            % (3) transform to strings with uniform length
            OutputTable_uni = strings(length(pools)+1, length(paras_names)+1);
            for idx = 1:length(OutputTable)
                % set same string length using pad
                if idx == 1
                    OutputTable_uni(idx,:) = pad(strsplit(OutputTable(idx),','),'right');
                else
                    OutputTable_uni(idx,:) = pad(strsplit(OutputTable(idx),','),'left');
                end
            end
            OutputTable_uni = join(OutputTable_uni',',   ');

            % (4) export to .excpara
            [file, path] = uiputfile('./Pools.excpara','Save pools parameters file');
            fid = fopen(fullfile(path,file),'w');
            if fid >= 0
                fprintf(fid,"\n======== CESTsimu, version " + app.appVersion + " =========\n");
                fprintf(fid,"Generate date: %s\n\n",string(datetime));
                fprintf(fid,"#========= Exchange pools info =========\n");
                for idx = 1:length(OutputTable_uni)
                    fprintf(fid,"%s\n",OutputTable_uni(idx));
                end
                fclose(fid);
                appendOutputLog(app, ": successfully exported");
            end
            figure(app.UIFigure);
        end

        % Button pushed function: LoadparamButton
        function LoadExchangeParamButtonPushed(app, event)
            % load exchange settings from .excpara file

            [file,path] = uigetfile({'*.excpara;*.yaml', 'supporting files (*.excpara,*.yaml)';...
                    '*.excpara','CESTsimu files (*.excpara)';...
                    '*.yaml','pulseq files (*.yaml)'},...
                    'Select the exchange pools file');
            figure(app.UIFigure);
            fid = fopen(fullfile(path,file),'r');
            if fid >= 0
                textTable = [];
                while ~feof(fid)  
                    textTable = [textTable;string(fgetl(fid))];
                end
                fclose(fid);

                filepostfix = strsplit(string(file),".");
                switch filepostfix(end)
                    case "excpara"
                        idxStart = find(contains(textTable,'#')==1) + 2;
                        for idx = idxStart:size(textTable,1)
                            textTemp = strsplit(textTable(idx,:),',');
                            if idx == idxStart
                                paras_value = zeros(size(textTable,1)-idxStart+1, length(textTemp)-1);
                            end
                            textTemp = regexprep(textTemp(2:end),'\s','');
                            paras_value(idx-idxStart+1,:) = str2double(textTemp);
                        end

                        app.T1_Water.Value = paras_value(1,1); % Water
                        app.T2_Water.Value = paras_value(2,1);
                        app.k_Water.Value = paras_value(3,1);
                        app.w_Water.Value = paras_value(4,1);
                        app.f_Water.Value = paras_value(5,1);
        
                        app.T1_Amide.Value = paras_value(1,2); % Amide
                        app.T2_Amide.Value = paras_value(2,2);
                        app.k_Amide.Value = paras_value(3,2);
                        app.w_Amide.Value = paras_value(4,2);
                        app.f_Amide.Value = paras_value(5,2);
        
                        app.T1_NOE.Value = paras_value(1,3); % NOE
                        app.T2_NOE.Value = paras_value(2,3);
                        app.k_NOE.Value = paras_value(3,3);
                        app.w_NOE.Value = paras_value(4,3);
                        app.f_NOE.Value = paras_value(5,3);
        
                        app.T1_MT.Value = paras_value(1,4); % MT
                        app.T2_MT.Value = paras_value(2,4);
                        app.k_MT.Value = paras_value(3,4);
                        app.w_MT.Value = paras_value(4,4);
                        app.f_MT.Value = paras_value(5,4);
        
                        app.T1_Guan.Value = paras_value(1,5); % Gua
                        app.T2_Guan.Value = paras_value(2,5);
                        app.k_Guan.Value = paras_value(3,5);
                        app.w_Guan.Value = paras_value(4,5);
                        app.f_Guan.Value = paras_value(5,5);
        
                        app.T1_Other1.Value = paras_value(1,6); % Other1
                        app.T2_Other1.Value = paras_value(2,6);
                        app.k_Other1.Value = paras_value(3,6);
                        app.w_Other1.Value = paras_value(4,6);
                        app.f_Other1.Value = paras_value(5,6);
        
                        app.T1_Other2.Value = paras_value(1,7); % Other2
                        app.T2_Other2.Value = paras_value(2,7);
                        app.k_Other2.Value = paras_value(3,7);
                        app.w_Other2.Value = paras_value(4,7);
                        app.f_Other2.Value = paras_value(5,7);
        
                        app.T1_Other3.Value = paras_value(1,8); % Other3
                        app.T2_Other3.Value = paras_value(2,8);
                        app.k_Other3.Value = paras_value(3,8);
                        app.w_Other3.Value = paras_value(4,8);
                        app.f_Other3.Value = paras_value(5,8);

                    case "yaml"
                        iniUIExcPool(app);
                        
                        % pre-process textTable for latter extraction
                        textTable = regexprep(textTable,'#(\s|\S)*$',''); % delete # note
                        textTable = regexprep(textTable,'}',' } '); % add blank
                        textTable = regexprep(textTable,'{',' { ');
                        idxTemp = find(contains(textTable,'mt_pool')==1); % add blank row
                        if ~isempty(idxTemp)
                            textTable = [textTable(1:idxTemp-1);" ";textTable(idxTemp:end)];
                        end
                        idxTemp = find(contains(textTable,'cest_pool')==1);
                        if ~isempty(idxTemp)
                            textTable = [textTable(1:idxTemp-1);" ";textTable(idxTemp:end)];
                        end
                        idxTemp = find(contains(textTable,'b0')==1);
                        if ~isempty(idxTemp)
                            textTable = [textTable(1:idxTemp-1);" ";textTable(idxTemp:end)];
                        end

                        % import B0/B1 inhomogenity
                        textTemp = textTable(contains(textTable,'b0_inhom')==1);
                        textTemp = strsplit(regexprep( textTemp,'\s',''),':');
                        app.B0inhomEditField.Value = str2double(textTemp(end));

                        textTemp = textTable(contains(textTable,'rel_b1')==1);
                        textTemp = strsplit(regexprep( textTemp,'\s',''),':');
                        app.B1inhomEditField.Value = str2double(textTemp(end));

                        % import water pool
                        idx_water = find(contains(textTable,'water_pool')==1);
                        if ~isempty(idx_water)
                            idx_end = idx_water;
                            strTemp = textTable(idx_end+1);
                            while ~strcmp(strTemp,"") && isempty(regexp(strTemp,'^\s*$', 'once'))
                                % text of pool hasnt ended, keep loading
                                idx_end = idx_end + 1;
                                strTemp = textTable(idx_end+1);
                            end
                            textTemp = textTable(idx_water : idx_end);
                            textTemp = regexprep( join(textTemp," "),',',' ');
                            textTemp = strsplit(textTemp);

                            % set UI
                            app.f_Water.Value = 111*str2num( textTemp(find(contains(textTemp,'f:')==1,1)+1) );
                            app.k_Water.Value = 1;
                            app.w_Water.Value = 0;
                            if isempty( find(contains(textTemp,'t1:')==1, 1) )
                                app.T1_Water.Value = 1/str2num( textTemp(find(contains(textTemp,'r1:')==1,1)+1) );
                            else
                                app.T1_Water.Value = str2num( textTemp(find(contains(textTemp,'t1:')==1,1)+1) );
                            end

                            if isempty( find(contains(textTemp,'t2:')==1, 1) )
                                app.T2_Water.Value = 1/str2num( textTemp(find(contains(textTemp,'r2:')==1,1)+1) );
                            else
                                app.T2_Water.Value = str2num( textTemp(find(contains(textTemp,'t2:')==1,1)+1) );
                            end
                        end
                        
                        % import MT pool
                        idx_mt = find(contains(textTable,'mt_pool')==1);
                        if ~isempty(idx_mt)
                            idx_end = idx_mt;
                            strTemp = textTable(idx_end+1);
                            while ~strcmp(strTemp,"") && isempty(regexp(strTemp,'^\s*$', 'once'))
                                % text of pool hasnt ended, keep loading
                                idx_end = idx_end + 1;
                                strTemp = textTable(idx_end+1);
                            end
                            textTemp = textTable(idx_mt : idx_end);
                            textTemp = regexprep( join(textTemp," "),',',' ');
                            textTemp = strsplit(textTemp);

                            % set UI
                            app.f_MT.Value = 111*str2num( textTemp(find(contains(textTemp,'f:')==1,1)+1) );
                            app.k_MT.Value = str2num( textTemp(find(contains(textTemp,'k:')==1,1)+1) );
                            app.w_MT.Value = str2num( textTemp(find(contains(textTemp,'w:')==1,1)+1) );
                            if isempty( find(contains(textTemp,'t1:')==1, 1) )
                                app.T1_MT.Value = 1/str2num( textTemp(find(contains(textTemp,'r1:')==1,1)+1) );
                            else
                                app.T1_MT.Value = str2num( textTemp(find(contains(textTemp,'t1:')==1,1)+1) );
                            end

                            if isempty( find(contains(textTemp,'t2:')==1, 1) )
                                app.T2_MT.Value = 1/str2num( textTemp(find(contains(textTemp,'r2:')==1,1)+1) );
                            else
                                app.T2_MT.Value = str2num( textTemp(find(contains(textTemp,'t2:')==1,1)+1) );
                            end
                        end

                        % import CEST pool (s)
                        idx_cest = find(contains(textTable,'cest_pool')==1);
                        if ~isempty(idx_cest)
                            idx_end = idx_cest;
                            strTemp = textTable(idx_end+1);
                            while ~strcmp(strTemp,"") && isempty(regexp(strTemp,'^\s*$', 'once'))
                                % text of pool hasnt ended, keep loading
                                idx_end = idx_end + 1;
                                strTemp = textTable(idx_end+1);
                            end
                            textTemp = textTable(idx_cest : idx_end);
                            textTemp = regexprep( join(textTemp," "),',',' ');
                            textTemp = regexprep(textTemp,'"',''''); % set "amide" to 'amide'
                            text_pools = regexp(textTemp,"'\S+':[^}]*}",'match'); % separate into pools

                            CESTpools_name = ["Amide", "NOE", "Gua", "MT"];
                            Otherpools_name = ["Other1","Other2","Other3"];
                            idx_flag_other = 1;
                            for idx_pool = 1:length(text_pools)
                                text_pool = text_pools(idx_pool);
                                poolName = regexp(text_pool,"'(\S)+'",'match');

                                idx_flag = 0; % determine which UI pool
                                for idx_check = 1:length(CESTpools_name)
                                    if contains(poolName,CESTpools_name(idx_check),'IgnoreCase',true)
                                        idx_flag = idx_check;
                                        break;
                                    end
                                end
                                if idx_flag == 0
                                    idx_flag = idx_flag_other + length(CESTpools_name);
                                    idx_flag_other = min(idx_flag_other + 1,length(Otherpools_name));
                                end

                                % set UI
                                text_pool = strsplit(text_pool);
                                switch idx_flag
                                    case 1
                                        app.f_Amide.Value = 111*str2num( text_pool(find(contains(text_pool,'f:')==1,1)+1) );
                                        app.k_Amide.Value = str2num( text_pool(find(contains(text_pool,'k:')==1,1)+1) );
                                        app.w_Amide.Value = str2num( text_pool(find(contains(text_pool,'w:')==1,1)+1) );
                                        if isempty( find(contains(text_pool,'t1:')==1, 1) )
                                            app.T1_Amide.Value = 1/str2num( text_pool(find(contains(text_pool,'r1:')==1,1)+1) );
                                        else
                                            app.T1_Amide.Value = str2num( text_pool(find(contains(text_pool,'t1:')==1,1)+1) );
                                        end
            
                                        if isempty( find(contains(text_pool,'t2:')==1, 1) )
                                            app.T2_Amide.Value = 1/str2num( text_pool(find(contains(text_pool,'r2:')==1,1)+1) );
                                        else
                                            app.T2_Amide.Value = str2num( text_pool(find(contains(text_pool,'t2:')==1,1)+1) );
                                        end
                                    case 2
                                        app.f_NOE.Value = 111*str2num( text_pool(find(contains(text_pool,'f:')==1,1)+1) );
                                        app.k_NOE.Value = str2num( text_pool(find(contains(text_pool,'k:')==1,1)+1) );
                                        app.w_NOE.Value = str2num( text_pool(find(contains(text_pool,'w:')==1,1)+1) );
                                        if isempty( find(contains(text_pool,'t1:')==1, 1) )
                                            app.T1_NOE.Value = 1/str2num( text_pool(find(contains(text_pool,'r1:')==1,1)+1) );
                                        else
                                            app.T1_NOE.Value = str2num( text_pool(find(contains(text_pool,'t1:')==1,1)+1) );
                                        end
            
                                        if isempty( find(contains(text_pool,'t2:')==1, 1) )
                                            app.T2_NOE.Value = 1/str2num( text_pool(find(contains(text_pool,'r2:')==1,1)+1) );
                                        else
                                            app.T2_NOE.Value = str2num( text_pool(find(contains(text_pool,'t2:')==1,1)+1) );
                                        end
                                    case 3
                                        app.f_Guan.Value = 111*str2num( text_pool(find(contains(text_pool,'f:')==1,1)+1) );
                                        app.k_Guan.Value = str2num( text_pool(find(contains(text_pool,'k:')==1,1)+1) );
                                        app.w_Guan.Value = str2num( text_pool(find(contains(text_pool,'w:')==1,1)+1) );
                                        if isempty( find(contains(text_pool,'t1:')==1, 1) )
                                            app.T1_Guan.Value = 1/str2num( text_pool(find(contains(text_pool,'r1:')==1,1)+1) );
                                        else
                                            app.T1_Guan.Value = str2num( text_pool(find(contains(text_pool,'t1:')==1,1)+1) );
                                        end
            
                                        if isempty( find(contains(text_pool,'t2:')==1, 1) )
                                            app.T2_Guan.Value = 1/str2num( text_pool(find(contains(text_pool,'r2:')==1,1)+1) );
                                        else
                                            app.T2_Guan.Value = str2num( text_pool(find(contains(text_pool,'t2:')==1,1)+1) );
                                        end
                                    case 4
                                        app.f_MT.Value = 111*str2num( text_pool(find(contains(text_pool,'f:')==1,1)+1) );
                                        app.k_MT.Value = str2num( text_pool(find(contains(text_pool,'k:')==1,1)+1) );
                                        app.w_MT.Value = str2num( text_pool(find(contains(text_pool,'w:')==1,1)+1) );
                                        if isempty( find(contains(text_pool,'t1:')==1, 1) )
                                            app.T1_MT.Value = 1/str2num( text_pool(find(contains(text_pool,'r1:')==1,1)+1) );
                                        else
                                            app.T1_MT.Value = str2num( text_pool(find(contains(text_pool,'t1:')==1,1)+1) );
                                        end
            
                                        if isempty( find(contains(text_pool,'t2:')==1, 1) )
                                            app.T2_MT.Value = 1/str2num( text_pool(find(contains(text_pool,'r2:')==1,1)+1) );
                                        else
                                            app.T2_MT.Value = str2num( text_pool(find(contains(text_pool,'t2:')==1,1)+1) );
                                        end
                                    case 5
                                        app.f_Other1.Value = 111*str2num( text_pool(find(contains(text_pool,'f:')==1,1)+1) );
                                        app.k_Other1.Value = str2num( text_pool(find(contains(text_pool,'k:')==1,1)+1) );
                                        app.w_Other1.Value = str2num( text_pool(find(contains(text_pool,'w:')==1,1)+1) );
                                        if isempty( find(contains(text_pool,'t1:')==1, 1) )
                                            app.T1_Other1.Value = 1/str2num( text_pool(find(contains(text_pool,'r1:')==1,1)+1) );
                                        else
                                            app.T1_Other1.Value = str2num( text_pool(find(contains(text_pool,'t1:')==1,1)+1) );
                                        end
            
                                        if isempty( find(contains(text_pool,'t2:')==1, 1) )
                                            app.T2_Other1.Value = 1/str2num( text_pool(find(contains(text_pool,'r2:')==1,1)+1) );
                                        else
                                            app.T2_Other1.Value = str2num( text_pool(find(contains(text_pool,'t2:')==1,1)+1) );
                                        end
                                    case 6
                                        app.f_Other2.Value = 111*str2num( text_pool(find(contains(text_pool,'f:')==1,1)+1) );
                                        app.k_Other2.Value = str2num( text_pool(find(contains(text_pool,'k:')==1,1)+1) );
                                        app.w_Other2.Value = str2num( text_pool(find(contains(text_pool,'w:')==1,1)+1) );
                                        if isempty( find(contains(text_pool,'t1:')==1, 1) )
                                            app.T1_Other2.Value = 1/str2num( text_pool(find(contains(text_pool,'r1:')==1,1)+1) );
                                        else
                                            app.T1_Other2.Value = str2num( text_pool(find(contains(text_pool,'t1:')==1,1)+1) );
                                        end
            
                                        if isempty( find(contains(text_pool,'t2:')==1, 1) )
                                            app.T2_Other2.Value = 1/str2num( text_pool(find(contains(text_pool,'r2:')==1,1)+1) );
                                        else
                                            app.T2_Other2.Value = str2num( text_pool(find(contains(text_pool,'t2:')==1,1)+1) );
                                        end
                                    case 7
                                        app.f_Other3.Value = 111*str2num( text_pool(find(contains(text_pool,'f:')==1,1)+1) );
                                        app.k_Other3.Value = str2num( text_pool(find(contains(text_pool,'k:')==1,1)+1) );
                                        app.w_Other3.Value = str2num( text_pool(find(contains(text_pool,'w:')==1,1)+1) );
                                        if isempty( find(contains(text_pool,'t1:')==1, 1) )
                                            app.T1_Other3.Value = 1/str2num( text_pool(find(contains(text_pool,'r1:')==1,1)+1) );
                                        else
                                            app.T1_Other3.Value = str2num( text_pool(find(contains(text_pool,'t1:')==1,1)+1) );
                                        end
            
                                        if isempty( find(contains(text_pool,'t2:')==1, 1) )
                                            app.T2_Other3.Value = 1/str2num( text_pool(find(contains(text_pool,'r2:')==1,1)+1) );
                                        else
                                            app.T2_Other3.Value = str2num( text_pool(find(contains(text_pool,'t2:')==1,1)+1) );
                                        end
                                end
                            end
                        end

                end
                appendOutputLog(app, ": successfully imported");
            end
        end

        % Button pushed function: PlotButton_Seq
        function PlotButton_SeqPushed(app, event)
            % plot saturation sequence
            pulseReadFromUI(app);
            pulse_cell = app.pulseCell;

            % (1) reorder all triple tuple [power1,phase1,duration1] 
            pulseSeqArray = [0,0,0];
            for pulseIdx = 1:length(pulse_cell)
                pulseSeqArray = [[pulseSeqArray;pulse_cell{pulseIdx}(1,1),pulse_cell{pulseIdx}(1,2),0];pulse_cell{pulseIdx}];
            end
            pulseSeqArray = [pulseSeqArray;[0,0,0]];

            % (2) calculate time stamp for each tuple
            timeArray = reshape(cumsum(pulseSeqArray(:,3)),[],1);

            % (3) scatter plot of amplitude and phase
            pulseSeqArray(:,1) = pulseSeqArray(:,1) / app.gamma_hz;
            app.UIAxes_Seq.XLim = [-0.05,timeArray(end)+0.05];
            B1rms = B1max2B1rms(app, app.B1maxEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
            title(app.UIAxes_Seq,"Sequence (B_{1rms}="+num2str(B1rms,'%.4f')+"uT)")

            yyaxis(app.UIAxes_Seq,"left");ylabel(app.UIAxes_Seq,"B_{1} [uT]")
            xlabel(app.UIAxes_Seq,"time [s]")
            app.UIAxes_Seq.YColor = 'black';
            app.UIAxes_Seq.YLim = [min(pulseSeqArray(:,1)),max(pulseSeqArray(:,1),[],'all')*1.2];
            plot(app.UIAxes_Seq,timeArray,pulseSeqArray(:,1),'k','LineWidth',1);
            
            yyaxis(app.UIAxes_Seq,"right");ylabel(app.UIAxes_Seq,"Phase angle [degree]");
            app.UIAxes_Seq.YLim = [0,370];
            plot(app.UIAxes_Seq,timeArray,pulseSeqArray(:,2)/pi*180,'LineWidth',1);

            yyaxis(app.UIAxes_Seq,"left");
        end

        % Button pushed function: SaveparamButton_2
        function SaveSaturationParamButtonPushed(app, event)
            % saturation settings
            B0infoTable =               ["#=============== B0 info ====================", " ";...
                                         "B0 (T)                 : ", num2str(app.B0EditField.Value);...
                                         "B0 inhomogeneity  (ppm): ", num2str(app.B0inhomEditField.Value);...
                                         "                         ", " "];
            switch app.FreqoffsmodeDropDown.Value
                case "Set offs."
                    FreqoffsTable =     ["#=============== Frequency offsets ==========", " ";...
                                         "Freq. offset mod       : ", app.FreqoffsmodeDropDown.Value;...
                                         "Freq. offset M0   (ppm): ", num2str(app.FreqoffsM0EditField.Value);...
                                         "Freq. offset from (ppm): ", num2str(app.FreqoffsfromEditField.Value);...
                                         "Freq. offset to   (ppm): ", num2str(app.FreqoffstoEditField.Value);...
                                         "Freq. offset step (ppm): ", num2str(app.FreqoffsstepEditField.Value);...
                                         "                         ", " "];
                case "Load offs."
                    freqoffs = reshape(app.freqoffs_global,1,[]);
                    Nshow_column = 10; % number of digits shown in 
                    Npad = Nshow_column - mod(length(freqoffs),Nshow_column); 
                    freqoffs_pad = reshape([string(freqoffs),repmat("",1,Npad)],Nshow_column,[]);
                    freqoffsTable = regexprep(join(pad(freqoffs_pad,'left')',', '),'(,\s*){2,}','');
                    freqoffsTable = regexprep(freqoffsTable,',\s*$',''); % delete comma and blank at the end

                    FreqoffsTable =     ["#=============== Frequency offsets ==========", " ";...
                                         "Freq. offset mod       : ", app.FreqoffsmodeDropDown.Value;...
                                         "Freq. offset M0   (ppm): ", num2str(app.FreqoffsM0EditField.Value);...
                                         "Freq. offset list (ppm): ", " ";...
                                         [freqoffsTable,repmat("",length(freqoffsTable),1)];...
                                         "                         ", " "];
            end
            
            pulsewaveform = app.pulseWaveform;
            PulseinfoTable =            ["#=============== Pulse info =================", " ";...
                                         "Post pulse delay(ms)   : ", num2str(app.PostpulsedelayEditField.Value);...
                                         "Shape type             : ", app.PulseshapeDropDown.Value];
            switch app.PulseshapeDropDown.Value
                case "Sinc"
                    PulseinfoTable =    [PulseinfoTable;...
                                         "Shape para1 (alpha)    : ", num2str(app.Shapepara1EditField.Value);...
                                         "Shape para2 (Nzero)    : ", num2str(app.Shapepara2EditField.Value)];
                case "Gauss"
                    PulseinfoTable =    [PulseinfoTable;...
                                         "Shape para1 (sigma)    : ", num2str(app.Shapepara1EditField.Value)];
                case "Fermi"
                    PulseinfoTable =    [PulseinfoTable;...
                                         "Shape para1 (t0/a)    : ", num2str(app.Shapepara1EditField.Value);...
                                         "Shape para2 (a)       : ", num2str(app.Shapepara2EditField.Value)];
            end
            PulseinfoTable =            [PulseinfoTable;...
                                         "B1_max (uT)            : ", num2str(app.B1maxEditField.Value);...
                                         "B1_rms (uT)            : ", num2str(app.B1rmsEditField.Value);...
                                         "B1 inhom.              : ", num2str(app.B1inhomEditField.Value);...
                                         "Pulse width (ms)       : ", num2str(app.PulsedurationEditField.Value);...
                                         "Pulse gap (ms)         : ", num2str(app.PulsedelayEditField.Value);...
                                         "Pulse repeat           : ", num2str(app.PulsenumberEditField.Value);...
                                         "Pulse phase cycle (deg): ", app.PulsephasecycEditField.Value;...
                                         "Compensate accum. phase: ", num2str(app.CompaccumphaseCheckBox.Value);...
                                         "Recovery time (s)      : ", num2str(app.RecoverytimeEditField.Value);...
                                         "Reset init. mag.       : ", num2str(app.ResetinitmagCheckBox.Value);...
                                         "                         ", " ";...
                                         "#=============== Pulse waveform ===========", " "];

            OutputTable = [B0infoTable;FreqoffsTable;PulseinfoTable];
            OutputTable_uni = join([OutputTable(:,1),pad(OutputTable(:,2),'left')],2);

            % % pulse sequence settings
            % %   store whole pulseCell
            % pulseSeqArray = [];
            % pulse_cell = pulseReadFromUI(app);
            % for pulseIdx = 1:length(pulse_cell)
            %     pulseSeqArray = [[pulseSeqArray;pulse_cell{pulseIdx}(1,1),pulse_cell{pulseIdx}(1,2),0];pulse_cell{pulseIdx}];
            % end
            % pulseSeqArray = [pulseSeqArray;[0,0,0]];
            % timeArray = reshape(cumsum(pulseSeqArray(:,3)),[],1);
            % pulseSeqArray(:,3) = timeArray*1000; %ms
            % pulseSeqArray_str = regexprep(regexprep(string(num2str(pulseSeqArray)),'(\S)(\s+)','$1,'),'\s+',''); % replace blank with comma
            % OutputTable_seq = strings(length(pulseSeqArray_str),3);
            % for idx = 1:length(pulseSeqArray_str)
            %     OutputTable_seq(idx,:) = strsplit(pulseSeqArray_str(idx),',');
            % end
            % % OutputTable_seq = [["Power (uT)","Phase (deg)","Time coor (ms)"];OutputTable_seq];
            % OutputTable_seq_uni = join([ ...
            %         pad(OutputTable_seq(:,1),'left'), ...
            %         pad(OutputTable_seq(:,2),'left'), ...
            %         pad(OutputTable_seq(:,3),'left')],', ',2);

            % export
            [file, path] = uiputfile('./Saturation.satpara','Save saturation parameters file');
            fid = fopen(fullfile(path,file),'w');
            if fid >= 0
                fprintf(fid,"\n============= CESTsimu, version " + app.appVersion + " =========\n");
                fprintf(fid,"Generate date: %s\n\n",string(datetime));
                for idx = 1:length(OutputTable_uni)
                    fprintf(fid,"%s\n",OutputTable_uni(idx));
                end

                fprintf(fid,"# num_samples " + num2str( length(pulsewaveform) ));                   
                for idx = 1:length(pulsewaveform)
                    fprintf(fid,"\n%s",string(pulsewaveform(idx)));
                end
                fclose(fid);
                appendOutputLog(app, ": successfully exported");
            end
            figure(app.UIFigure);
        end

        % Button pushed function: LoadparamButton_2
        function LoadSaturationParamButtonPushed(app, event)
            % load saturation settings from .satpara file

            [file,path] = uigetfile({'*.satpara;*.seq', 'supporting files (*.satpara,*.seq)';...
                    '*.satpara','CESTsimu files (*.satpara)';...
                    '*.seq','pulseq files (*.seq)'},...
                    'Select the pulse file');
            figure(app.UIFigure);
            fid = fopen(fullfile(path,file),'r');
            if fid >= 0
                textTable = [];
                while ~feof(fid)  
                    textTable = [textTable;string(fgetl(fid))];
                end
                fclose(fid);

                filepostfix = strsplit(string(file),".");
                try
                    switch filepostfix(end)
                        case "satpara"
                            loadSaturationfromCESTsimu(app, textTable, fullfile(path,file));
                            loadPulseFromFile(app,fullfile(path,file)); % store as app.pulseCell
                        case "seq"
                            loadSaturationfromPulseq(app, textTable, fullfile(path,file));
                    end
                    pulseReadFromUI(app);
                catch ME
                    appendOutputLog(app, ": import error, "+ME.message);
                end
            else
                appendOutputLog(app, ": import cancel");
                
            end

        end

        % Value changed function: FreqoffsfromEditField, 
        % ...and 2 other components
        function FreqoffsUIArrayValueChanged(app, event)
            % re-calculate freq. offs array when UI value changed
            app.freqoffs_global = app.FreqoffsfromEditField.Value : app.FreqoffsstepEditField.Value : app.FreqoffstoEditField.Value;
            app.FreqoffsppmSlider.Limits = [min(app.freqoffs_global,[],'all'),max(app.freqoffs_global,[],'all')];
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            [file, path] = uiputfile('./CESTsimuData.mat','Save CESTsimu data');
            if file ~= 0
                %% saturation information
                satInfo.B0 = num2str(app.B0EditField.Value)+" (T)";
                satInfo.B1max = app.B1maxEditField.Value;
                satInfo.pulseshape = app.pulseCell;

                % deltaB0
                if ~isempty(app.B0inhomMap)
                    satInfo.B0inhomoMap_ppm = app.B0inhomMap;
                end
                satInfo.B0inhomoScalar_ppm = app.B0inhomEditField.Value;
                if app.ScalarButton_B0.Value == 1
                    satInfo.B0inhomo_mode = 'Scalar';
                else
                    satInfo.B0inhomo_mode = 'Map';
                end

                % rB1
                if ~isempty(app.B1inhomMap)
                    satInfo.B1inhomoMap_relative = app.B1inhomMap;
                end
                satInfo.B1inhomoScalar_relative = app.B1inhomEditField.Value;
                if app.ScalarButton_B1.Value == 1
                    satInfo.B1inhomo_mode = 'Scalar';
                else
                    satInfo.B1inhomo_mode = 'Map';
                end
                
                dataInfo.saturation = satInfo;
                
                %% pools information
                pools = poolsRead(app);
                for i = 1:numel(pools)
                    pools{i}{6} = pools{i}{6}*111; % change concentration unit to [M]
                end

                % transform into strings
                pools_names = strings(length(pools),1);
                paras_names = ["T1 [s]", "T2 [s]", "Exch. Rate [Hz]", "dw [ppm]", "f [M])"];
                paras = zeros(length(pools),length(pools{1})-1);
                for poolIdx = 1:length(pools)
                    pools_names(poolIdx) = pools{poolIdx}{1};
                    for termIdx = 2:length(pools{1})
                        paras(poolIdx,termIdx-1) = pools{poolIdx}{termIdx};
                    end
                end
                paras_value = string(num2str(paras));
                paras_value = regexprep(regexprep(paras_value,'(\S)(\s+)','$1,'),'\s+',''); % replace blank with comma
                PoolsTable = [join(['Pool',paras_names],',');pools_names+','+paras_value];
    
                % transform to strings with uniform length
                PoolsTable_uni = strings(length(pools)+1, length(paras_names)+1);
                for idx = 1:length(PoolsTable)
                    % set same string length using pad
                    if idx == 1
                        PoolsTable_uni(idx,:) = pad(strsplit(PoolsTable(idx),','),'right');
                    else
                        PoolsTable_uni(idx,:) = pad(strsplit(PoolsTable(idx),','),'left');
                    end
                end
                PoolsTable_uni = join(PoolsTable_uni',',   ');
    
                % resort paras order to be consistent with UI
                dataInfo.defaultPools = PoolsTable_uni;

                %% experiment information
                expInfo.poolsArr = app.phantomExpSetting;
                expInfo.NPhantom = app.GroupsNumber.Value;
                dataInfo.experiment = expInfo;

                %% simulated data
                zspecHoldonCell = app.ZspecHoldOnCell;

                if ~isempty(app.phantomExpSetting)
                    offs = app.freqoffs_global;
                    roi = Phantom2DCal_ROI(app);
                    z_m0 = zspecBatchCalculatefromExpUI_single(app,app.FreqoffsM0EditField.Value);
                    img_m0 = Phantom2DCal_CEST(app,roi,z_m0,z_m0*0);
                    img = app.phantom_CEST;
                    img_MTR = app.phantom_MTR;
                    zspecExpBatch = app.ZspecBatch;
                    zspecBatch_m0 = app.ZspecM0Batch;
                    MTRasymExpBatch = app.MTRasymBatch;
                    % add noise for saving
                    if app.GaussNoiseOnCheckBox.Value == 1
                        sigma = app.sigmaEditField.Value;
                        img = img + sigma*randn(size(img));
                        img_m0 = img_m0 + sigma*randn(size(img_m0));
                        zspecExpBatch = zspecExpBatch + sigma*randn(size(zspecExpBatch));
    
                        img_MTR = img_MTR + sqrt(2)*sigma*randn(size(img_MTR));
                        MTRasymExpBatch = MTRasymExpBatch + sqrt(2)*sigma*randn(size(MTRasymExpBatch));
                    end

                    % save to .mat file
                    save(fullfile(path,file),'offs','img','img_MTR','img_m0','roi','dataInfo','zspecExpBatch','zspecBatch_m0','zspecHoldonCell','MTRasymExpBatch','-mat');
                    appendOutputLog(app, ": data successfully saved to .MAT file");

                    % save app.phantomExpSetting to .xlsx file
                    [file_excel, path_excel] = uiputfile({'*.xlsx'},'Save CESTsimu data to EXCEL',fullfile(path,strrep(file,'.mat','.xlsx')));
                    if file_excel ~= 0
                        try
                            sheetNames = {'T1 (s)','T2 (s)','k (Hz)','w (ppm)','f (M)'};
                            filename = fullfile(path_excel,file_excel);
                            for i = 1:length(app.phantomExpSetting)
                                writetable(app.phantomExpSetting{i}, filename, 'Sheet', sheetNames{i},"AutoFitWidth",false,'WriteMode','overwrite');
                            end
                            appendOutputLog(app, ": Tables have been saved to Excel with separate sheets");
                        catch ME
                            appendOutputLog(app, ": error with Excel saving error, "+ME.message);
                        end
                    else
                        appendOutputLog(app, ": Saving to Excel cancel");
                    end

                else
                    save(fullfile(path,file),'dataInfo','zspecHoldonCell','-mat');
                    appendOutputLog(app, ": phantom setting is empty, only 1D data is saved");
                end
            else
                appendOutputLog(app, ": Saving to .MAT cancel");
            end
            figure(app.UIFigure);
        end

        % Value changed function: PulseshapeDropDown
        function PulseshapeDropDownValueChanged(app, event)
            
            app.LoadfromEditField_pulse.Enable = "off";
            app.RFsamplesEditField.Enable = "on";
            app.Shapepara1EditField.Enable = 'off';
            app.Shapepara2EditField.Enable = 'off';
            switch app.PulseshapeDropDown.Value
                case "Other"
                    loadPulseFromFile(app);
                case "CW"
                    app.RFsamplesEditField.Enable = "off";
                    app.SelectedpulseshapeEditField.Value = "CW";
                case "Sinc"
                    app.Shapepara1EditField.Enable = 'on';
                    app.Shapepara2EditField.Enable = 'on';
                    app.Shapepara1EditField.Value = 0.46;
                    app.Shapepara2EditField.Value = 1;
                    app.SelectedpulseshapeEditField.Value = "Sinc";
                    app.Shapepara1EditFieldLabel.Text = '$\alpha$';
                    app.Shapepara2EditFieldLabel.Text = '$N$';
                case "Gauss"
                    app.Shapepara1EditField.Enable = 'on';
                    app.Shapepara2EditField.Enable = 'off';
                    app.Shapepara1EditField.Value = 1/7.434;
                    app.SelectedpulseshapeEditField.Value = "Gauss";
                    app.Shapepara1EditFieldLabel.Text = '$\sigma$';
                case "Fermi"
                    app.Shapepara1EditField.Enable = 'on';
                    app.Shapepara2EditField.Enable = 'on';
                    app.Shapepara1EditField.Value = 16;
                    app.Shapepara2EditField.Value = 1/(2*16+13.81);
                    app.SelectedpulseshapeEditField.Value = "Fermi";
                    app.Shapepara1EditFieldLabel.Text = '$t_0 / a$';
                    app.Shapepara2EditFieldLabel.Text = '$a$';
            end
            pulseReadFromUI(app);
            app.B1maxEditField.Value = B1rms2B1max(app, app.B1rmsEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
            
        end

        % Value changed function: B1rmsEditField
        function B1rmsEditFieldValueChanged(app, event)
            app.B1maxEditField.Value = B1rms2B1max(app, app.B1rmsEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
            pulseReadFromUI(app);
        end

        % Value changed function: B1maxEditField
        function B1maxEditFieldValueChanged(app, event)
            app.B1rmsEditField.Value = B1max2B1rms(app, app.B1maxEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
            pulseReadFromUI(app);
        end

        % Value changed function: PostpulsedelayEditField, 
        % ...and 4 other components
        function PulseUIparamsEditFieldValueChanged(app, event)
            pulseReadFromUI(app);
            app.B1maxEditField.Value = B1rms2B1max(app, app.B1rmsEditField.Value, app.PulsedurationEditField.Value, app.PulsedelayEditField.Value);
        end

        % Button pushed function: RandomB0map
        function RandomB0mapButtonPushed(app, event)
            % generate random B0 inhomogenous map

            Npixel = 256;
            FOV = 15; % unit cm
            
            %% setting range
            % normal B0inhomo range
            % with loadings: 1st order B0 inhomo < 0.1ppm/cm
            % with loadings: 2nd order B0 inhomo < 0.01ppm/cm^2
            % after 2nd shimming: B0 inhomo ~ 0.01-0.1 ppm
            % 
            % ref: a linescan based FASTMAP article
            % Gruetter, R. (1993). "Automatic, localized in vivo adjustment of all first‐and second‐order shim coils." MRM 29(6): 804-811. 
            
            B0_0th_max = 1; % maximum global B0 inhom, unit ppm
            B0_1st_max = 0.02; % maximum first order B0 inhom, unit ppm/cm
            B0_2nd_max = 0.001; % maximum second order B0 inhom, unit ppm/cm^2
            
            %% random B0 inhomo coeffi
            % 3 first-order, 5 second-order, refer to FASTMAP Shim Tool Manual
            B0shft = B0_0th_max*(rand(1)*2-1);
            Bx = B0_1st_max*(rand(1)*2-1);
            By = B0_1st_max*(rand(1)*2-1);
            Bz = B0_1st_max*(rand(1)*2-1);
            Bx2y2 = B0_2nd_max*(rand(1)*2-1);
            Bzx = B0_2nd_max*(rand(1)*2-1);
            Bz2 = B0_2nd_max*(rand(1)*2-1);
            Bzy = B0_2nd_max*(rand(1)*2-1);
            Bxy = B0_2nd_max*(rand(1)*2-1);
            
            %% synthesis B0 map
            x = linspace(-FOV/2, FOV/2, Npixel);
            y = linspace(-FOV/2, FOV/2, Npixel);
            [Y,X] = meshgrid(x,y);
            B0map = B0shft + Bx .* X + By .* Y + Bx2y2 .* (X.^2-Y.^2) + Bxy .* X .* Y;
            app.B0inhomMap = B0map;

            imshow(B0map,[],'Parent',app.UIAxes_B0map,'InitialMagnification','fit')
            title(app.UIAxes_B0map,"B_{0} inhom. map [ppm]");
            colormap(app.UIAxes_B0map,jet);
            colorbar(app.UIAxes_B0map);

        end

        % Button pushed function: RandomB1map
        function RandomB1mapButtonPushed(app, event)
            Npixel = 256;

            %% randomization
            B1max = 0.8 + rand(1)*0.4; % 80% - 120%
            a = 0.8 + rand(1)*0.4; % 0.8-1.2
            Bx = 0.001*(rand(1)*2-1);
            By = 0.0005*(rand(1)*2-1);
            Bx2 = 0.00001*(rand(1)*2-1);
            By2 = 0.00002*(rand(1)*2-1);

            %% distribution
            % Gaussian
            sigma = Npixel/a;

            x = linspace(-Npixel/2, Npixel/2, Npixel);
            y = linspace(-Npixel/2, Npixel/2, Npixel);
            [Y,X] = meshgrid(x,y);
            B1map_poly = 1 + Bx .* X + By .* Y + Bx2 .* X.^2 + By2 .* Y.^2;
            B1map_2D = B1max * exp(-X.^2/2/sigma^2) .* exp(-Y.^2/2/sigma^2) .* B1map_poly; % relative ratio
            app.B1inhomMap = B1map_2D;

            imshow(B1map_2D*100,[],'Parent',app.UIAxes_B1map,'InitialMagnification','fit')
            title(app.UIAxes_B1map,"B_{1} inhom. map [%]");
            colormap(app.UIAxes_B1map,jet);
            colorbar(app.UIAxes_B1map);
        end

        % Button pushed function: CalButton_CEST, CalButton_MTR
        function CalButton_PhantomPushed(app, event)
            % Calculate phantom map after pushing Calculate button
            % OUTPUT:
            %   app.phantom_CEST: noisy phantom CEST image of Ngroup
            %       size [Nx,Ny,Nf]
            %   app.phantom_MTR: noisy phantom MTR image of Ngroup
            %       size [Nx,Ny,Nf]

            app.stopFlag = false;
            app.ZspecHoldOnCell = [];
            phantom_roi = Phantom2DCal_ROI(app);
            if isempty(app.phantomExpSetting)
                appendOutputLog(app, ": Error, experiment phantom setting is empty, please set the experiment in ‘Exp. setting’");
                return
            end
            if app.ScalarButton_B0.Value == 1 && app.ScalarButton_B1.Value == 1
                
                % Calculate zspec at m0 freq. offs. for normalization display
                zspecm0batch = zspecBatchCalculatefromExpUI_single(app,app.FreqoffsM0EditField.Value);
                app.ZspecM0Batch = zspecm0batch;
                
                % Calculate zspec batch for latter display
                [zspecBatch, zspecBatch_neg, flag] = zspecBatchCalculatefromExpUI_pair(app,app.freqoffs_global); 
                if flag == 1
                    appendOutputLog(app, ": calculation terminated");
                    app.stopFlag = false;
                    return;
                end
                app.ZspecBatch = zspecBatch; % [Ngroups, Noffs]app.B0inhomMap
     
                % Calculate MTRasym
                app.MTRasymBatch = zspecBatch_neg - zspecBatch;

                % Calculate phantom images
                [phantom_2D_CEST,phantom_2D_MTR] = Phantom2DCal_CEST(app,phantom_roi,zspecBatch,app.MTRasymBatch);
                [phantom_2D_M0,~] = Phantom2DCal_CEST(app,phantom_roi,zspecm0batch,zspecm0batch);
                app.phantom_M0 = phantom_2D_M0;
                app.phantom_CEST = phantom_2D_CEST;
                app.phantom_MTR = phantom_2D_MTR;
            elseif app.MapButton_B0.Value == 1 || app.MapButton_B1.Value == 1
                % construct B1/B0 map
                if app.ScalarButton_B1.Value == 1
                    B1map = ones(size(phantom_roi)) * app.B1inhomEditField.Value;
                else
                    B1map = imresize(app.B1inhomMap,size(phantom_roi),"nearest");
                end
                if app.ScalarButton_B0.Value == 1
                    B0map = ones(size(phantom_roi)) * app.B0inhomEditField.Value;
                else
                    B0map = imresize(app.B0inhomMap,size(phantom_roi),"nearest");
                end

                % load experiment value
                poolsArr = phanExpSetToPoolsArr(app);
                Ngroups = app.GroupsNumber.Value;
                Ncolumn = ceil(sqrt(Ngroups));
                Nrow = Ncolumn;

                % calculate zspec for each group
                ex_offs = app.freqoffs_global;
                phantom_2D_M0 = zeros(size(phantom_roi));
                phantom_2D_CEST = zeros([size(phantom_roi),length(ex_offs)]);
                phantom_2D_MTR = zeros([size(phantom_roi),length(ex_offs)]);
                zspecBatch_m0_mean = zeros(Ngroups,1);
                zspecBatch_mean = zeros(Ngroups,length(ex_offs));
                MTRspecBatch_mean = zeros(Ngroups,length(ex_offs));
                Np = 128; % size of subimage
                Nf = length(ex_offs);
                outputLogTemp = app.OutputLogTextArea.Value;
                cnt = 0;
                for groupidx = 1:Ngroups
                    % subimage range
                    rowRange = (ceil(groupidx/Ncolumn)-1)*Np + (1:Np); % [1,1,1,2,2,2]
                    colRrange = mod(groupidx-1,Nrow)*Np + (1:Np); % [1,2,3,1,2,3]
                    B1maptemp = B1map(rowRange,colRrange);
                    B0maptemp = B0map(rowRange,colRrange);
                    mask = phantom_roi(rowRange,colRrange);

                    pools = poolsArr{groupidx};
                    pools = poolsCheck(app,pools);

                    for offsidx = 1:Nf+1
                        percent = cnt/(Nf+1)/Ngroups*100;
                        cnt = cnt + 1;
                        updateLogRealtime(app,[outputLogTemp;"Calculation process: " + num2str(percent,'%.2f') + "%"]);

                        if offsidx == 1 % M0 img
                            offs_temp = app.FreqoffsM0EditField.Value; 

                            [CESTmap_temp, ~, magn_P_rec,magn_N_rec,flag] = zmap_1pool_1offs(app,offs_temp,pools,B1maptemp,B0maptemp,mask);
                            phantom_2D_M0(rowRange,colRrange) = CESTmap_temp;

                            zspecBatch_m0_mean(groupidx) = mean( CESTmap_temp(mask~=0),'all' );
                        else % CEST imgs
                            offs_temp = ex_offs(offsidx-1); 

                            % calculate subimage value (with B1/B0 inhom. , without noise)
                            [CESTmap_temp, MTRmap_temp,magn_P_rec,magn_N_rec,flag] = ...
                                zmap_1pool_1offs(app,offs_temp,pools,B1maptemp,B0maptemp,...
                                                 mask,magn_P_rec,magn_N_rec);
                            phantom_2D_CEST(rowRange,colRrange,offsidx-1) = CESTmap_temp;
                            phantom_2D_MTR(rowRange,colRrange,offsidx-1) = MTRmap_temp;
    
                            % zspecbatch as mean value in roi
                            zspecBatch_mean(groupidx,offsidx-1) = mean( CESTmap_temp(mask~=0),'all' );
                            MTRspecBatch_mean(groupidx,offsidx-1) = mean( MTRmap_temp(mask~=0),'all' );
                        
                        end
                        if flag == 1
                            appendOutputLog(app, ": calculation terminated");
                            app.stopFlag = false;
                            return;
                        end

                    end
                end
                app.ZspecM0Batch = zspecBatch_m0_mean;
                app.ZspecBatch = zspecBatch_mean;
                app.MTRasymBatch = MTRspecBatch_mean;
                app.phantom_M0 = phantom_2D_M0;
                app.phantom_CEST = phantom_2D_CEST;
                app.phantom_MTR = phantom_2D_MTR;
                app.OutputLogTextArea.Value = [outputLogTemp;string(datetime('now','Format','HH:mm:ss'))+...
                                              ": Calculation done"];
                scroll(app.OutputLogTextArea,'bottom');

            end

            % plot
            ex_offs_temp = app.FreqoffsppmSlider.Value;
            [~,ind_offs] = min(abs(app.freqoffs_global - ex_offs_temp)); % nearest offs
            ex_offs = app.freqoffs_global(ind_offs);
            Phantom2DPlot(app,ind_offs);
            zspec1DPlot_joint(app, ex_offs)
            
        end

        % Button pushed function: SaveplotButton
        function SaveplotButtonPushed(app, event)
            [file, path] = uiputfile({'*.png';'*.jpg';'*.tif'}, 'Save Image As','saved_plot.png');
            if isequal(file, 0) || isequal(path, 0)
                figure(app.UIFigure);
                return;
                
            end
            
            % saving dir
            file_name = fullfile(path, file);
            % folder_name = uigetdir('Select Folder to Save Plot');
            % if folder_name == 0
            %     return; 
            % end
            % 
            % % output filename
            % file_name = fullfile(folder_name, 'saved_plot.png');
            
            exportgraphics(app.UIAxes, file_name, 'Resolution', 300); 

            appendOutputLog(app, ": plot saved successfully");
            figure(app.UIFigure);
        end

        % Value changed function: FreqOffs_precise
        function FreqOffs_preciseValueChanged(app, event)
            ex_offs_temp = app.FreqOffs_precise.Value;
            [~,ind_offs] = min(abs(app.freqoffs_global - ex_offs_temp)); % nearest offs
            ex_offs = app.freqoffs_global(ind_offs);

            app.FreqoffsppmSlider.Value = ex_offs;
            app.FreqOffs_precise.Value = ex_offs;

            Phantom2DPlot(app,ind_offs)
            zspec1DPlot_joint(app, ex_offs)
        end

        % Value changed function: GaussNoiseOnCheckBox
        function GaussNoiseOnCheckBoxValueChanged(app, event)
            value = app.GaussNoiseOnCheckBox.Value;
            if value == 1
                app.sigmaEditField.Enable = "on";
                app.sigmaLabel.Enable = "on";
            else
                app.sigmaEditField.Enable = "off";
                app.sigmaLabel.Enable = "off";
            end
        end

        % Value changed function: ResetinitmagCheckBox
        function ResetinitmagCheckBoxValueChanged(app, event)
            value = app.ResetinitmagCheckBox.Value;
            if value == 1
                app.RecoverytimeEditField.Enable = "off";
            else
                app.RecoverytimeEditField.Enable = "on";
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag = true;
        end

        % Value changed function: RFsamplesEditField
        function RFsamplesEditFieldValueChanged(app, event)
            pulseReadFromUI(app);
        end

        % Button pushed function: ImportB0map
        function ImportB0mapButtonPushed(app, event)
            [file,path] = uigetfile({'*.mat','MATLAB files (*.mat)'},...
                    'Select the MAT file for B0map');
            figure(app.UIFigure);
            if isequal(file, 0)
                appendOutputLog(app, ": import cancel");
                return;
            end
            
            %% load B0 map
            matData = load(fullfile(path, file));
            if ~isfield(matData, 'B0map')
                appendOutputLog(app, ": The selected file does not contain a variable named ""B0map"".");
                return;
            end
            B0map = matData.B0map;
            
            if ~ismatrix(B0map)
                appendOutputLog(app, ": B0map is not a 2D matrix.");
                return;
            end

            %% polynomial interpolate
            polyDegree = 7; % polynomial degree
            res = size(B0map);
            B0mapROI = logical(B0map);
            ind = find(B0mapROI);    % indexes corresponding to the support
    
            % Define the points inside the support
            [X1,X2] = GenerateFullCart2DGrid(app,res);
            R = [X1(ind)'/res(1);X2(ind)'/res(2)];
            M = Polynomial2DMatrix(app,R,polyDegree);
            
            coeff = Fitting(app,M,B0map(ind),'accurate');
            
            R_full = [X1(:)'/res(1);X2(:)'/res(2)];
            M_full = Polynomial2DMatrix(app,R_full,polyDegree);
            B0map = reshape(M_full * coeff,res);

            %% display
            B0map = imresize(B0map,[256,256],"nearest");
            app.B0inhomMap = B0map;
            appendOutputLog(app, ": Successfully import B0map.");

            imshow(B0map,[],'Parent',app.UIAxes_B0map,'InitialMagnification','fit')
            title(app.UIAxes_B0map,"B_{0} inhom. map [ppm]");
            colormap(app.UIAxes_B0map,jet);
            colorbar(app.UIAxes_B0map);

        end

        % Button pushed function: ImportB1map
        function ImportB1mapButtonPushed(app, event)
            [file,path] = uigetfile({'*.mat','MATLAB files (*.mat)'},...
                    'Select the MAT file for B1map');
            figure(app.UIFigure);
            if isequal(file, 0)
                appendOutputLog(app, ": import cancel");
                return;
            end
            
            %% load B1 map
            matData = load(fullfile(path, file));
            if ~isfield(matData, 'B1map')
                appendOutputLog(app, ": The selected file does not contain a variable named ""B1map"".");
                return;
            end
            B1map = matData.B1map;
            
            if ~ismatrix(B1map)
                appendOutputLog(app, ": B1map is not a 2D matrix.");
                return;
            end
            
            %% polynomial interpolate
            polyDegree = 7; % polynomial degree
            res = size(B1map);
            B1mapROI = logical(B1map);
            ind = find(B1mapROI);    % indexes corresponding to the support
    
            % Define the points inside the support
            [X1,X2] = GenerateFullCart2DGrid(app,res);
            R = [X1(ind)'/res(1);X2(ind)'/res(2)];
            M = Polynomial2DMatrix(app,R,polyDegree);
            
            coeff = Fitting(app,M,B1map(ind),'accurate');
            
            R_full = [X1(:)'/res(1);X2(:)'/res(2)];
            M_full = Polynomial2DMatrix(app,R_full,polyDegree);
            B1map = reshape(M_full * coeff,res);

            %% display
            B1map = imresize(B1map,[256,256],"nearest");
            app.B1inhomMap = B1map;
            appendOutputLog(app, ": Successfully import B1map.");

            imshow(B1map*100,[],'Parent',app.UIAxes_B1map,'InitialMagnification','fit')
            title(app.UIAxes_B1map,"B_{1} inhom. map [%]");
            colormap(app.UIAxes_B1map,jet);
            colorbar(app.UIAxes_B1map);
        end

        % Button pushed function: ExpsettingButton
        function ExpsettingButtonPushed(app, event)
            % set phantom experiment para. in a pop-up UI table

            %% initialize experiment
            Ng = app.GroupsNumber.Value;
            varName = {'Water', 'Amide', 'rNOE','MT','Guan','Other1','Other2','Other3'};
            if isempty(app.phantomExpSetting)
                pools = poolsRead(app); % [8,1] cell array
                for i = 1:numel(pools)
                    pools{i}{6} = pools{i}{6}*111; % change concentration unit to [M]
                end
                app.phantomExpSetting = cell(5,1); 

                for termIdx = 1:5 % for [T1,T2,k,w,f_M]
                    paraList = zeros(1,8);
                    for poolIdx = 1:8 % for [water,amide,rNOE,MT]
                        paraList(poolIdx) = pools{poolIdx}{termIdx+1};
                    end
                    cols = repmat(paraList,Ng,1);
                    app.phantomExpSetting{termIdx} = table(cols(:,1),cols(:,2),...
                        cols(:,3),cols(:,4),cols(:,5),cols(:,6),cols(:,7),cols(:,8),...
                        'VariableNames', varName, ...
                        'RowNames',cellstr("Phantom "+string(1:Ng))');
                end
            end

            %% basic UItable
            % create pop-up windows
            appendOutputLog(app, ": Setting 2D phantom via UI table");
            fig_UItab = uifigure('Name', 'Phantom Experiment Setting');
            fig_UItab.Position = [100 100 831 584];

            % Create QuciksettingLabel
            uilabel(fig_UItab, 'Text', 'Quick setting', 'Position', [28 537 157 32], ...
                    'FontSize',24,'FontWeight','bold');

            % create para. selection meanu
            uilabel(fig_UItab, 'Text', 'Parameter table of ', 'Position', [25 427 222 32], ...
                    'FontSize',24,'FontWeight','bold');
            Paradropdown = uidropdown(fig_UItab, ...
                'Items', {'f [M]', 'k [Hz]', 'T1 [s]', 'T2 [s]', 'w [ppm]', }, ...
                'Position', [260 427 84 31], 'FontWeight', 'bold', ...
                'ValueChangedFcn', @(Paradropdown,event) UpdateUITable(app, fig_UItab));
            Paradropdown.Tag = 'ParaDrop';
            
            % create UITable component, initially display concentration
            uit = uitable(fig_UItab, 'Data', app.phantomExpSetting{5}, 'Position', [60 65 720 345], ...
                                'CellEditCallback', @(src,event) UpdateTableData(app, fig_UItab));
            uit.ColumnEditable = true;
            uit.Tag = 'DynamicTable'; % enabling searching uitable in other function
            
            % create confirm button
            uibutton(fig_UItab, 'Text', 'confirm', 'Position', [50 16 88 32], ...
                'FontSize',18,'FontWeight','bold','FontColor',[1 1 1],...
                'BackgroundColor',[0 0.4471 0.7412],...
                'ButtonPushedFcn', @(Confirmbtn,event) ConfirmCallback_UITable(app, fig_UItab));

            %% quick assignment
            % value from
            uilabel(fig_UItab, 'Text', 'Value from', 'Position', [327 498 77 22], ...
                'FontSize',14,'FontWeight','bold','HorizontalAlignment','right');
            valueFromBox = uieditfield(fig_UItab, 'numeric', 'Position', [412 498 55 22]);
            valueFromBox.Tag = 'valueFrom';

            % value to
            uilabel(fig_UItab, 'Text', 'Value to', 'Position', [506 498 59 22], ...
                'FontSize',14,'FontWeight','bold','HorizontalAlignment','right');
            valueToBox = uieditfield(fig_UItab, 'numeric', 'Position', [571 498 55 22]);
            valueToBox.Tag = 'valueTo';
            valueToBox.Value = 0.5;

            % pool select
            uilabel(fig_UItab, 'Text', 'Pool select', 'Position', [97 498 79 22], ...
                    'FontSize',14,'FontWeight','bold','HorizontalAlignment','right');
            poolDropdown = uidropdown(fig_UItab, ...
                'Items', varName, ...
                'Position', [191 498 100 22]);
            poolDropdown.Tag = 'poolDrop';
            poolDropdown.Value = varName{2};

            % Generate button
            uibutton(fig_UItab, 'Text', 'Assign', 'Position', [682 494 84 30], ...
                'FontSize',18,'FontWeight','bold','FontColor',[1 1 1],...
                'BackgroundColor',[0 0.4471 0.7412],...
                'ButtonPushedFcn', @(btn,event) QuickGenerate_UITable(app, fig_UItab));


        end

        % Button pushed function: ClearsettingButton
        function ClearsettingButtonPushed(app, event)
            app.phantomExpSetting = {};
        end

        % Button pushed function: LoadsettingButton
        function LoadsettingButtonPushed(app, event)
            
            [file,path] = uigetfile({'*.mat;*.xlsx', 'MAT and Excel Files (*.mat, *.xlsx)';......
                     '*.mat','MATLAB files (*.mat)';...
                     '*.xlsx','EXCEL files (*.xlsx)'},...
                    'Select a MAT or Excel file for Experiment Setting');
            figure(app.UIFigure);
            if isequal(file, 0)
                appendOutputLog(app, ": import cancel");
                return;
            end

            filename = fullfile(path, file);
            [~, ~, ext] = fileparts(filename);

            try
                if strcmpi(ext, '.mat')
                    %% MAT file
                    matData = load(fullfile(path, file));
            
                    % load experiment setting
                    if isfield(matData, 'dataInfo') && isfield(matData.dataInfo, 'experiment') && ...
                            isfield(matData.dataInfo.experiment, 'poolsArr')
                                app.phantomExpSetting = matData.dataInfo.experiment.poolsArr;
                                appendOutputLog(app, ": Successfully import experiment setting.");
                    else
                        appendOutputLog(app, ": The selected file does not contain dataInfo.experiment.poolsArr.");
                        return;
                    end
                    app.GroupsNumber.Value = matData.dataInfo.experiment.NPhantom;
        
                    % load B0 and B1
                    if isfield(matData.dataInfo.saturation, 'B0inhomoMap_ppm')
                        app.B0inhomMap = matData.dataInfo.saturation.B0inhomoMap_ppm;
                        imshow(app.B0inhomMap,[],'Parent',app.UIAxes_B0map,'InitialMagnification','fit')
                        title(app.UIAxes_B0map,"B_{0} inhom. map [ppm]");
                        colormap(app.UIAxes_B0map,jet);
                        colorbar(app.UIAxes_B0map);
                    end
                    if isfield(matData.dataInfo.saturation, 'B1inhomoMap_relative')
                        app.B1inhomMap = matData.dataInfo.saturation.B1inhomoMap_relative;
                        imshow(app.B1inhomMap*100,[],'Parent',app.UIAxes_B1map,'InitialMagnification','fit')
                        title(app.UIAxes_B1map,"B_{1} inhom. map [%]");
                        colormap(app.UIAxes_B1map,jet);
                        colorbar(app.UIAxes_B1map);
                    end
                    app.B0inhomEditField.Value = matData.dataInfo.saturation.B0inhomoScalar_ppm;
                    app.B1inhomEditField.Value = matData.dataInfo.saturation.B1inhomoScalar_relative;
                    switch matData.dataInfo.saturation.B0inhomo_mode
                        case 'Scalar'
                            app.ScalarButton_B0.Value = 1;
                        case 'Map'
                            app.MapButton_B0.Value = 1;
                    end
                    switch matData.dataInfo.saturation.B1inhomo_mode
                        case 'Scalar'
                            app.ScalarButton_B1.Value = 1;
                        case 'Map'
                            app.MapButton_B1.Value = 1;
                    end
                elseif strcmpi(ext, '.xlsx')
                    %% EXCEL file
                    sheetNames = {'T1 (s)','T2 (s)','k (Hz)','w (ppm)','f (M)'};
                    tables = cell(1, numel(sheetNames));
                    
                    for i = 1:numel(sheetNames)
                        tables{i} = readtable(filename, 'Sheet', sheetNames{i});
                    end

                    app.phantomExpSetting = tables;
                    appendOutputLog(app, ": Successfully import experiment setting.");
                end
            catch fileError
                appendOutputLog(app, ": Failed to process file: "+fileError);
            end

        end

        % Value changed function: GroupsNumber
        function GroupsNumberValueChanged(app, event)
            app.phantomExpSetting = {};
        end

        % Button pushed function: RFDesignButton
        function RFDesignButtonPushed(app, event)
            %% RF design table

            % create pop-up windows
            appendOutputLog(app, ": Shaped RF design via UI table");
            fig_UItab = uifigure('Name', 'Shaped RF design','Position',[100 100 649 506]);
            uilabel(fig_UItab,'Text','Shaped pulse design','Position',[10 467 247 32],'FontSize',24,'FontWeight','bold');

            % create UIAxes for RF display
            uiaxes(fig_UItab, 'Position',[308 51 328 266],'Tag','RFdesign_UIAxes');
            uibutton(fig_UItab, 'Text', 'Plot', 'Position', [512 324 100 26],'FontColor',[1 1 1],'BackgroundColor',[0 0.4471 0.7412],...
                'ButtonPushedFcn', @(Plotbtn,event) Plot_RFUITable(app, fig_UItab));

            % for editting
            uilabel(fig_UItab, 'Text', 'Select shape', 'Position', [384 438 86 22],'FontSize',14,'HorizontalAlignment','right');
            uidropdown(fig_UItab,'Items', {'Sinc', 'Gauss', 'Fermi'},'Value', 'Sinc',...
                'Tag', 'RFtypedrop','Position', [485 438 100 22], 'FontWeight', 'bold', ...
                'ValueChangedFcn', @(RFtypedropdown,event) UpdateRFdesignUITable(app, fig_UItab));

            uilabel(fig_UItab,'Position', [418 396 69 22],'Text','RF samples','HorizontalAlignment','right');
            uieditfield(fig_UItab,'numeric','Position',[502 396 47 22],'ValueDisplayFormat','%.0f','Value',200,'Tag','RFSampleEdit','Limits',[1,Inf],'RoundFractionalValues','on');

            uilabel(fig_UItab, 'Interpreter','latex','Position', [358 361 25 22],'Tag','TextPar1','Text','$\alpha$','HorizontalAlignment','right');
            uieditfield(fig_UItab,'numeric','Position',[406 361 47 22],'ValueDisplayFormat','%.5f','Value',0.46,'Tag','Editpar1');

            uilabel(fig_UItab, 'Interpreter','latex','Position', [487 361 25 22],'Tag','TextPar2','Text','$N$','HorizontalAlignment','right');
            uieditfield(fig_UItab,'numeric','Position',[536 361 47 22],'Limits',[0 Inf],'Value',1,'Tag','Editpar2');

            % for export
            uibutton(fig_UItab, 'Text', 'Cancel', 'Position', [511 17 100 23],'FontColor',[1 1 1],'BackgroundColor',[0 0.4471 0.7412],...
                'ButtonPushedFcn', @(Cancelbtn,event) Cancel_RFUITable(app, fig_UItab));
            uibutton(fig_UItab, 'Text', 'Confirm', 'Position', [321 17 100 23],'FontColor',[1 1 1],'BackgroundColor',[0 0.4471 0.7412],...
                'ButtonPushedFcn', @(Cancelbtn,event) Confirm_RFUITable(app, fig_UItab));

            %% Principle illustration
            uilabel(fig_UItab, 'Text', 'Sinc', 'Position', [17 440 35 22],'FontSize',14,'FontWeight','bold');
            uilabel(fig_UItab, 'Text', 'Gaussian', 'Position', [17 287 68 22],'FontSize',14,'FontWeight','bold');
            uilabel(fig_UItab, 'Text', 'Fermi', 'Position', [17 146 43 22],'FontSize',14,'FontWeight','bold');
            uilabel(fig_UItab, 'Interpreter','latex','Position', [103 440 89 22],'Text','$t\in [-0.5,0.5]$','FontColor',[0 0.4471 0.7412],'FontWeight','bold');
            uilabel(fig_UItab, 'Interpreter','latex','Position', [35 407 261 34],'Text','$\hat{B}_1(t,\alpha,N)=[(1-\alpha)+\alpha cos(2\pi t)]\frac{sin(2\pi N t)}{2\pi N t}$');
            uilabel(fig_UItab, 'Interpreter','latex','Position', [35 248 138 40],'Text','$\hat{B}_1(t,\sigma)=exp\bigg(-\frac{t^2}{2\sigma^2}\bigg)$');
            uilabel(fig_UItab, 'Interpreter','latex','Position', [35 91 180 56],'Text','$\hat{B}_1(t,t_0,a)=\frac{1}{ 1+exp\bigg({\frac{|t|-t_0}{a}  \bigg)  }$');
            
            
            uilabel(fig_UItab, 'Interpreter','latex','Position', [17 324 277 84],'WordWrap','on','FontColor',[1 1 1],'BackgroundColor',[0.651 0.651 0.651], ...
                'Text',{'(1) $\alpha$ : weighted coefficient, $\alpha=0.5$ for Hanning window, $\alpha=0.46$ for Hamming window'; ''; '(2) $N$: number of zeros on one side'});
            uilabel(fig_UItab, 'Interpreter','latex','Position', [17 184 279 65],'WordWrap','on','FontColor',[1 1 1],'BackgroundColor',[0.651 0.651 0.651], ...
                'Text','(1) $\sigma$ : determines width of pulse');
            uilabel(fig_UItab, 'Interpreter','latex','Position', [17 17 279 75],'WordWrap','on','FontColor',[1 1 1],'BackgroundColor',[0.651 0.651 0.651], ...
                'Text',{'(1) $t_0$ : determines width of pass-band'; ''; '(2) $a$: determines width of transition-band'});

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {672, 672, 672};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {672, 672};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x', 335};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Saturation Settings';
            app.TLabel = uilabel(app.LeftPanel);
            app.TLabel.Text = 'T';
            app.ppmLabel = uilabel(app.LeftPanel);
            app.ppmLabel.Text = 'ppm';
            app.ppmLabel_3 = uilabel(app.LeftPanel);
            app.ppmLabel_3.Text = 'ppm';
            app.ppmLabel_4 = uilabel(app.LeftPanel);
            app.ppmLabel_4.Text = 'ppm';
            app.ppmLabel_5 = uilabel(app.LeftPanel);
            app.ppmLabel_5.Text = 'ppm';
            app.LoadparamButton_2 = uibutton(app.LeftPanel, 'push');
            app.LoadparamButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadSaturationParamButtonPushed, true);
            app.LoadparamButton_2.Text = 'Load param';
            app.SaveparamButton_2 = uibutton(app.LeftPanel, 'push');
            app.SaveparamButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveSaturationParamButtonPushed, true);
            app.SaveparamButton_2.Text = 'Save param';
            app.ppmLabel_6 = uilabel(app.LeftPanel);
            app.ppmLabel_6.Text = 'ppm';
            app.B0DropDownLabel = uilabel(app.LeftPanel);
            app.B0DropDownLabel.Text = 'B0';
            app.B0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.B0EditField.Limits = [0.05 20];
            app.B0EditField.ValueDisplayFormat = '%.1f';
            app.B0EditField.Value = 3;
            app.B0inhomEditFieldLabel = uilabel(app.LeftPanel);
            app.B0inhomEditFieldLabel.Text = 'B0 inhom.';
            app.B0inhomEditField = uieditfield(app.LeftPanel, 'numeric');
            app.B0inhomEditField.Limits = [-1000 1000];
            app.FreqoffsfromEditFieldLabel = uilabel(app.LeftPanel);
            app.FreqoffsfromEditFieldLabel.Text = 'Freq. offs. from';
            app.FreqoffsfromEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FreqoffsfromEditField.ValueChangedFcn = createCallbackFcn(app, @FreqoffsUIArrayValueChanged, true);
            app.FreqoffsfromEditField.Value = -10;
            app.FreqoffstoEditFieldLabel = uilabel(app.LeftPanel);
            app.FreqoffstoEditFieldLabel.Text = 'Freq. offs. to';
            app.FreqoffstoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FreqoffstoEditField.ValueChangedFcn = createCallbackFcn(app, @FreqoffsUIArrayValueChanged, true);
            app.FreqoffstoEditField.Value = 10;
            app.FreqoffsstepEditFieldLabel = uilabel(app.LeftPanel);
            app.FreqoffsstepEditFieldLabel.Text = 'Freq. offs. step';
            app.FreqoffsstepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FreqoffsstepEditField.ValueChangedFcn = createCallbackFcn(app, @FreqoffsUIArrayValueChanged, true);
            app.FreqoffsstepEditField.Value = 0.25;
            app.FreqoffsM0EditFieldLabel = uilabel(app.LeftPanel);
            app.FreqoffsM0EditFieldLabel.Text = 'Freq. offs. M0';
            app.FreqoffsM0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.FreqoffsM0EditField.Value = -300;
            app.FreqoffsmodeDropDownLabel = uilabel(app.LeftPanel);
            app.FreqoffsmodeDropDownLabel.Text = 'Freq. offs. mode';
            app.FreqoffsmodeDropDown = uidropdown(app.LeftPanel);
            app.FreqoffsmodeDropDown.Items = {'Set offs.', 'Load offs.'};
            app.FreqoffsmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqoffsModeDropDownValueChanged, true);
            app.FreqoffsmodeDropDown.Value = 'Set offs.';
            app.LoadfromEditField_2Label = uilabel(app.LeftPanel);
            app.LoadfromEditField_2Label.Text = 'Load from';
            app.LoadfromEditField_freqoffs = uieditfield(app.LeftPanel, 'text');
            app.TabGroup3 = uitabgroup(app.LeftPanel);
            app.BasicTab = uitab(app.TabGroup3);
            app.BasicTab.Title = 'Basic';
            app.uTLabel = uilabel(app.BasicTab);
            app.uTLabel.Text = 'uT';
            app.msLabel = uilabel(app.BasicTab);
            app.msLabel.Text = 'ms';
            app.msLabel_2 = uilabel(app.BasicTab);
            app.msLabel_2.Text = 'ms';
            app.msLabel_3 = uilabel(app.BasicTab);
            app.msLabel_3.Text = 'ms';
            app.uTLabel_2 = uilabel(app.BasicTab);
            app.uTLabel_2.Text = 'uT';
            app.B1maxLabel = uilabel(app.BasicTab);
            app.B1maxLabel.Text = 'B1max';
            app.B1maxEditField = uieditfield(app.BasicTab, 'numeric');
            app.B1maxEditField.Limits = [0 Inf];
            app.B1maxEditField.ValueDisplayFormat = '%.4f';
            app.B1maxEditField.ValueChangedFcn = createCallbackFcn(app, @B1maxEditFieldValueChanged, true);
            app.B1maxEditField.Value = 0.8;
            app.B1rmsEditFieldLabel = uilabel(app.BasicTab);
            app.B1rmsEditFieldLabel.Text = 'B1rms';
            app.B1rmsEditField = uieditfield(app.BasicTab, 'numeric');
            app.B1rmsEditField.Limits = [0 Inf];
            app.B1rmsEditField.ValueDisplayFormat = '%.4f';
            app.B1rmsEditField.ValueChangedFcn = createCallbackFcn(app, @B1rmsEditFieldValueChanged, true);
            app.B1rmsEditField.Value = 0.8;
            app.PulseshapeDropDownLabel = uilabel(app.BasicTab);
            app.PulseshapeDropDownLabel.Text = 'Pulse shape';
            app.PulseshapeDropDown = uidropdown(app.BasicTab);
            app.PulseshapeDropDown.Items = {'CW', 'Sinc', 'Gauss', 'Fermi', 'Other'};
            app.PulseshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseshapeDropDownValueChanged, true);
            app.PulseshapeDropDown.Value = 'CW';
            app.PulsedurationEditFieldLabel = uilabel(app.BasicTab);
            app.PulsedurationEditFieldLabel.Text = 'Pulse duration';
            app.PulsedurationEditField = uieditfield(app.BasicTab, 'numeric');
            app.PulsedurationEditField.Limits = [0 Inf];
            app.PulsedurationEditField.ValueChangedFcn = createCallbackFcn(app, @PulseUIparamsEditFieldValueChanged, true);
            app.PulsedurationEditField.Value = 2000;
            app.PulsedelayEditFieldLabel = uilabel(app.BasicTab);
            app.PulsedelayEditFieldLabel.Text = 'Pulse delay';
            app.PulsedelayEditField = uieditfield(app.BasicTab, 'numeric');
            app.PulsedelayEditField.Limits = [0 Inf];
            app.PulsedelayEditField.ValueChangedFcn = createCallbackFcn(app, @PulseUIparamsEditFieldValueChanged, true);
            app.PulsenumberEditFieldLabel = uilabel(app.BasicTab);
            app.PulsenumberEditFieldLabel.Text = 'Pulse number';
            app.PulsenumberEditField = uieditfield(app.BasicTab, 'numeric');
            app.PulsenumberEditField.Limits = [1 Inf];
            app.PulsenumberEditField.RoundFractionalValues = 'on';
            app.PulsenumberEditField.ValueDisplayFormat = '%.0f';
            app.PulsenumberEditField.ValueChangedFcn = createCallbackFcn(app, @PulseUIparamsEditFieldValueChanged, true);
            app.PulsenumberEditField.Value = 1;
            app.PostpulsedelayEditFieldLabel = uilabel(app.BasicTab);
            app.PostpulsedelayEditFieldLabel.Text = 'Post pulse delay';
            app.PostpulsedelayEditField = uieditfield(app.BasicTab, 'numeric');
            app.PostpulsedelayEditField.Limits = [0 Inf];
            app.PostpulsedelayEditField.ValueChangedFcn = createCallbackFcn(app, @PulseUIparamsEditFieldValueChanged, true);
            app.PostpulsedelayEditField.Value = 6.5;
            app.LoadfromEditField_2Label_2 = uilabel(app.BasicTab);
            app.LoadfromEditField_2Label_2.Text = 'Load from';
            app.LoadfromEditField_pulse = uieditfield(app.BasicTab, 'text');
            app.B1inhomEditFieldLabel = uilabel(app.BasicTab);
            app.B1inhomEditFieldLabel.Text = 'B1 inhom.';
            app.B1inhomEditField = uieditfield(app.BasicTab, 'numeric');
            app.B1inhomEditField.Limits = [-1000 1000];
            app.B1inhomEditField.Value = 1;
            app.ResetinitmagCheckBox = uicheckbox(app.BasicTab);
            app.ResetinitmagCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetinitmagCheckBoxValueChanged, true);
            app.ResetinitmagCheckBox.Tooltip = {'Reset initial magnetization vector. Turning on means TR delay is not considered.'};
            app.ResetinitmagCheckBox.Text = 'Reset init. mag.';
            app.ResetinitmagCheckBox.Value = true;
            app.sLabel = uilabel(app.BasicTab);
            app.sLabel.Text = 's';
            app.RecoverytimeEditFieldLabel = uilabel(app.BasicTab);
            app.RecoverytimeEditFieldLabel.Text = 'Recovery time';
            app.RecoverytimeEditField = uieditfield(app.BasicTab, 'numeric');
            app.RecoverytimeEditField.Limits = [0 Inf];
            app.RecoverytimeEditField.ValueDisplayFormat = '%.3f';
            app.RecoverytimeEditField.Enable = 'off';
            app.RecoverytimeEditField.Tooltip = {''};
            app.RecoverytimeEditField.Value = 1;
            app.AdvanceTab = uitab(app.TabGroup3);
            app.AdvanceTab.Title = 'Advance';
            app.RFsamplesEditFieldLabel = uilabel(app.AdvanceTab);
            app.RFsamplesEditFieldLabel.Text = 'RF samples';
            app.RFsamplesEditField = uieditfield(app.AdvanceTab, 'numeric');
            app.RFsamplesEditField.Limits = [1 Inf];
            app.RFsamplesEditField.RoundFractionalValues = 'on';
            app.RFsamplesEditField.ValueDisplayFormat = '%.0f';
            app.RFsamplesEditField.ValueChangedFcn = createCallbackFcn(app, @RFsamplesEditFieldValueChanged, true);
            app.RFsamplesEditField.Enable = 'off';
            app.RFsamplesEditField.Tooltip = {'defines the number of samples used for the simulation of shaped pulses'};
            app.RFsamplesEditField.Value = 200;
            app.SequenceLabel = uilabel(app.AdvanceTab);
            app.SequenceLabel.Text = 'Sequence';
            app.ShapedRFLabel = uilabel(app.AdvanceTab);
            app.ShapedRFLabel.Text = 'Shaped RF';
            app.RFDesignButton = uibutton(app.AdvanceTab, 'push');
            app.RFDesignButton.ButtonPushedFcn = createCallbackFcn(app, @RFDesignButtonPushed, true);
            app.RFDesignButton.Text = 'RF Design';
            app.Shapepara1EditFieldLabel = uilabel(app.AdvanceTab);
            app.Shapepara1EditFieldLabel.Interpreter = 'latex';
            app.Shapepara1EditFieldLabel.Text = 'Shape para.1';
            app.Shapepara1EditField = uieditfield(app.AdvanceTab, 'numeric');
            app.Shapepara1EditField.Enable = 'off';
            app.Shapepara1EditField.Tooltip = {'For shaped RF definition, alpha for "Sinc", sigma for "Gauss", ratio (t0/a) for "Fermi"'};
            app.Shapepara2EditFieldLabel = uilabel(app.AdvanceTab);
            app.Shapepara2EditFieldLabel.Interpreter = 'latex';
            app.Shapepara2EditFieldLabel.Text = 'Shape para.2';
            app.Shapepara2EditField = uieditfield(app.AdvanceTab, 'numeric');
            app.Shapepara2EditField.Enable = 'off';
            app.Shapepara2EditField.Tooltip = {'For shaped RF definition, N for "Sinc", a for "Fermi"'};
            app.NoteOtherpulseparametersaresetunderBasicLabel = uilabel(app.AdvanceTab);
            app.NoteOtherpulseparametersaresetunderBasicLabel.Text = 'Note: Other pulse parameters are set under Basic';
            app.SelectedpulseshapeLabel = uilabel(app.AdvanceTab);
            app.SelectedpulseshapeLabel.Text = 'Selected pulse shape:';
            app.SelectedpulseshapeEditField = uieditfield(app.AdvanceTab, 'text');
            app.SelectedpulseshapeEditField.Editable = 'off';
            app.SelectedpulseshapeEditField.Enable = 'off';
            app.CompaccumphaseCheckBox = uicheckbox(app.AdvanceTab);
            app.CompaccumphaseCheckBox.Tooltip = {'compensate accumulated RF phase for pulsed saturation'};
            app.CompaccumphaseCheckBox.Text = 'Comp. accum. phase';
            app.CompaccumphaseCheckBox.Value = true;
            app.degLabel = uilabel(app.AdvanceTab);
            app.degLabel.Text = 'deg';
            app.PulsephasecycEditFieldLabel = uilabel(app.AdvanceTab);
            app.PulsephasecycEditFieldLabel.Text = 'Pulse phase cyc.';
            app.PulsephasecycEditField = uieditfield(app.AdvanceTab, 'text');
            app.PulsephasecycEditField.ValueChangedFcn = createCallbackFcn(app, @PulseUIparamsEditFieldValueChanged, true);
            app.PulsephasecycEditField.Value = '0,0,0,0';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Title = 'Exchange Settings';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.ZspectraTab = uitab(app.TabGroup);
            app.ZspectraTab.Title = 'Z-spectra';
            app.UIAxes = uiaxes(app.ZspectraTab);
            title(app.UIAxes, 'Simulation results')
            xlabel(app.UIAxes, 'Offset [ppm]')
            ylabel(app.UIAxes, 'Z')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.XTick = [-10 -5 0 5 10];
            app.UIAxes.XTickLabel = {'-10'; '-5'; '0'; '5'; '10'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.PlotButton = uibutton(app.ZspectraTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearplotButton = uibutton(app.ZspectraTab, 'push');
            app.ClearplotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButtonPushed, true);
            app.ClearplotButton.Text = 'Clear plot';
            app.SaveplotButton = uibutton(app.ZspectraTab, 'push');
            app.SaveplotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotButtonPushed, true);
            app.SaveplotButton.Text = 'Save plot';
            app.SavedataButton = uibutton(app.ZspectraTab, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.StopButton = uibutton(app.ZspectraTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SaturationSequenceTab = uitab(app.TabGroup);
            app.SaturationSequenceTab.Title = 'Saturation Sequence';
            app.UIAxes_Seq = uiaxes(app.SaturationSequenceTab);
            title(app.UIAxes_Seq, 'Sequence')
            xlabel(app.UIAxes_Seq, 'time [s]')
            ylabel(app.UIAxes_Seq, 'B_{1} [uT]')
            app.PlotButton_Seq = uibutton(app.SaturationSequenceTab, 'push');
            app.PlotButton_Seq.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_SeqPushed, true);
            app.PlotButton_Seq.Text = 'Plot';
            app.PoolLabel = uilabel(app.CenterPanel);
            app.PoolLabel.Text = 'Pool';
            app.fMLabel = uilabel(app.CenterPanel);
            app.fMLabel.Text = 'f [M]';
            app.kHzLabel = uilabel(app.CenterPanel);
            app.kHzLabel.Text = 'k [Hz]';
            app.wppmLabel = uilabel(app.CenterPanel);
            app.wppmLabel.Text = 'w [ppm]';
            app.f_Water = uieditfield(app.CenterPanel, 'numeric');
            app.f_Water.Limits = [0 Inf];
            app.f_Water.Value = 111;
            app.f_Amide = uieditfield(app.CenterPanel, 'numeric');
            app.f_Amide.Limits = [0 Inf];
            app.f_Amide.Value = 0.1;
            app.f_NOE = uieditfield(app.CenterPanel, 'numeric');
            app.f_NOE.Limits = [0 Inf];
            app.f_NOE.Value = 0.5;
            app.f_MT = uieditfield(app.CenterPanel, 'numeric');
            app.f_MT.Limits = [0 Inf];
            app.f_MT.Value = 15;
            app.f_Guan = uieditfield(app.CenterPanel, 'numeric');
            app.f_Guan.Limits = [0 Inf];
            app.f_Guan.Value = 0.1;
            app.f_Other1 = uieditfield(app.CenterPanel, 'numeric');
            app.f_Other1.Limits = [0 Inf];
            app.f_Other2 = uieditfield(app.CenterPanel, 'numeric');
            app.f_Other2.Limits = [0 Inf];
            app.f_Other3 = uieditfield(app.CenterPanel, 'numeric');
            app.f_Other3.Limits = [0 Inf];
            app.WaterLabel = uilabel(app.CenterPanel);
            app.WaterLabel.Text = 'Water';
            app.AmideLabel = uilabel(app.CenterPanel);
            app.AmideLabel.Text = 'Amide';
            app.NOELabel = uilabel(app.CenterPanel);
            app.NOELabel.Text = 'NOE';
            app.MTLabel = uilabel(app.CenterPanel);
            app.MTLabel.Text = 'MT';
            app.GuanLabel = uilabel(app.CenterPanel);
            app.GuanLabel.Text = 'Guan';
            app.Other1Label = uilabel(app.CenterPanel);
            app.Other1Label.Text = 'Other1';
            app.Other2Label = uilabel(app.CenterPanel);
            app.Other2Label.Text = 'Other2';
            app.Other3Label = uilabel(app.CenterPanel);
            app.Other3Label.Text = 'Other3';
            app.k_Water = uieditfield(app.CenterPanel, 'numeric');
            app.k_Water.Value = 1;
            app.k_Amide = uieditfield(app.CenterPanel, 'numeric');
            app.k_Amide.Value = 50;
            app.k_NOE = uieditfield(app.CenterPanel, 'numeric');
            app.k_NOE.Value = 20;
            app.k_MT = uieditfield(app.CenterPanel, 'numeric');
            app.k_MT.Value = 30;
            app.k_Guan = uieditfield(app.CenterPanel, 'numeric');
            app.k_Guan.Value = 400;
            app.k_Other1 = uieditfield(app.CenterPanel, 'numeric');
            app.k_Other2 = uieditfield(app.CenterPanel, 'numeric');
            app.k_Other3 = uieditfield(app.CenterPanel, 'numeric');
            app.w_Water = uieditfield(app.CenterPanel, 'numeric');
            app.w_Amide = uieditfield(app.CenterPanel, 'numeric');
            app.w_Amide.Value = 3.5;
            app.w_NOE = uieditfield(app.CenterPanel, 'numeric');
            app.w_NOE.Value = -3.5;
            app.w_MT = uieditfield(app.CenterPanel, 'numeric');
            app.w_MT.Value = -2.5;
            app.w_Guan = uieditfield(app.CenterPanel, 'numeric');
            app.w_Guan.Value = 2;
            app.w_Other1 = uieditfield(app.CenterPanel, 'numeric');
            app.w_Other2 = uieditfield(app.CenterPanel, 'numeric');
            app.w_Other3 = uieditfield(app.CenterPanel, 'numeric');
            app.T1sLabel = uilabel(app.CenterPanel);
            app.T1sLabel.Text = 'T1 [s]';
            app.T2sLabel = uilabel(app.CenterPanel);
            app.T2sLabel.Text = 'T2 [s]';
            app.T1_Water = uieditfield(app.CenterPanel, 'numeric');
            app.T1_Water.Limits = [1e-05 Inf];
            app.T1_Water.Value = 1;
            app.T1_Amide = uieditfield(app.CenterPanel, 'numeric');
            app.T1_Amide.Value = 1;
            app.T1_NOE = uieditfield(app.CenterPanel, 'numeric');
            app.T1_NOE.Value = 1.3;
            app.T1_MT = uieditfield(app.CenterPanel, 'numeric');
            app.T1_MT.Value = 1;
            app.T1_Guan = uieditfield(app.CenterPanel, 'numeric');
            app.T1_Guan.Value = 1;
            app.T1_Other1 = uieditfield(app.CenterPanel, 'numeric');
            app.T1_Other2 = uieditfield(app.CenterPanel, 'numeric');
            app.T1_Other3 = uieditfield(app.CenterPanel, 'numeric');
            app.T2_Water = uieditfield(app.CenterPanel, 'numeric');
            app.T2_Water.Limits = [1e-05 Inf];
            app.T2_Water.Value = 0.04;
            app.T2_Amide = uieditfield(app.CenterPanel, 'numeric');
            app.T2_Amide.Value = 0.1;
            app.T2_NOE = uieditfield(app.CenterPanel, 'numeric');
            app.T2_NOE.Value = 0.005;
            app.T2_MT = uieditfield(app.CenterPanel, 'numeric');
            app.T2_MT.Value = 4e-05;
            app.T2_Guan = uieditfield(app.CenterPanel, 'numeric');
            app.T2_Guan.Value = 0.1;
            app.T2_Other1 = uieditfield(app.CenterPanel, 'numeric');
            app.T2_Other2 = uieditfield(app.CenterPanel, 'numeric');
            app.T2_Other3 = uieditfield(app.CenterPanel, 'numeric');
            app.LoadparamButton = uibutton(app.CenterPanel, 'push');
            app.LoadparamButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExchangeParamButtonPushed, true);
            app.LoadparamButton.Text = 'Load param';
            app.SaveparamButton = uibutton(app.CenterPanel, 'push');
            app.SaveparamButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExchangeParamButtonPushed, true);
            app.SaveparamButton.Text = 'Save param';
            app.OutputLogTextAreaLabel = uilabel(app.CenterPanel);
            app.OutputLogTextAreaLabel.Text = 'Output Log';
            app.OutputLogTextArea = uitextarea(app.CenterPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Phantom Settings';
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.ZvalueTab = uitab(app.TabGroup2);
            app.ZvalueTab.Title = 'Z-value';
            app.UIAxes_2D_CEST = uiaxes(app.ZvalueTab);
            app.UIAxes_2D_CEST.XTickLabel = '';
            app.UIAxes_2D_CEST.YTickLabel = '';
            app.UIAxes_2D_CEST.Visible = 'off';
            app.ShowButton_CEST = uibutton(app.ZvalueTab, 'push');
            app.ShowButton_CEST.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_phantomPushed, true);
            app.ShowButton_CEST.Text = 'Show';
            app.CalButton_CEST = uibutton(app.ZvalueTab, 'push');
            app.CalButton_CEST.ButtonPushedFcn = createCallbackFcn(app, @CalButton_PhantomPushed, true);
            app.CalButton_CEST.Text = 'Calculate';
            app.MTRasymTab = uitab(app.TabGroup2);
            app.MTRasymTab.Title = 'MTRasym';
            app.UIAxes_2D_MTR = uiaxes(app.MTRasymTab);
            app.UIAxes_2D_MTR.XTickLabel = '';
            app.UIAxes_2D_MTR.YTickLabel = '';
            app.UIAxes_2D_MTR.Visible = 'off';
            app.ShowButton_MTR = uibutton(app.MTRasymTab, 'push');
            app.ShowButton_MTR.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_phantomPushed, true);
            app.ShowButton_MTR.Text = 'Show';
            app.CalButton_MTR = uibutton(app.MTRasymTab, 'push');
            app.CalButton_MTR.ButtonPushedFcn = createCallbackFcn(app, @CalButton_PhantomPushed, true);
            app.CalButton_MTR.Text = 'Calculate';
            app.dB0Tab = uitab(app.TabGroup2);
            app.dB0Tab.Tooltip = {''};
            app.dB0Tab.Title = 'dB0';
            app.UIAxes_B0map = uiaxes(app.dB0Tab);
            app.UIAxes_B0map.XTickLabel = '';
            app.UIAxes_B0map.YTickLabel = '';
            app.UIAxes_B0map.Visible = 'off';
            app.RandomB0map = uibutton(app.dB0Tab, 'push');
            app.RandomB0map.ButtonPushedFcn = createCallbackFcn(app, @RandomB0mapButtonPushed, true);
            app.RandomB0map.Text = 'Random';
            app.B0inhommodeButtonGroup = uibuttongroup(app.dB0Tab);
            app.B0inhommodeButtonGroup.Title = 'B0 inhom. mode';
            app.ScalarButton_B0 = uiradiobutton(app.B0inhommodeButtonGroup);
            app.ScalarButton_B0.Text = 'Scalar';
            app.ScalarButton_B0.Value = true;
            app.MapButton_B0 = uiradiobutton(app.B0inhommodeButtonGroup);
            app.MapButton_B0.Text = 'Map';
            app.ImportB0map = uibutton(app.dB0Tab, 'push');
            app.ImportB0map.ButtonPushedFcn = createCallbackFcn(app, @ImportB0mapButtonPushed, true);
            app.ImportB0map.Text = 'Import';
            app.rB1Tab = uitab(app.TabGroup2);
            app.rB1Tab.Tooltip = {''};
            app.rB1Tab.Title = 'rB1';
            app.UIAxes_B1map = uiaxes(app.rB1Tab);
            app.UIAxes_B1map.XTickLabel = '';
            app.UIAxes_B1map.YTickLabel = '';
            app.UIAxes_B1map.Visible = 'off';
            app.RandomB1map = uibutton(app.rB1Tab, 'push');
            app.RandomB1map.ButtonPushedFcn = createCallbackFcn(app, @RandomB1mapButtonPushed, true);
            app.RandomB1map.Text = 'Random';
            app.B1inhommodeButtonGroup = uibuttongroup(app.rB1Tab);
            app.B1inhommodeButtonGroup.Title = 'B1 inhom. mode';
            app.ScalarButton_B1 = uiradiobutton(app.B1inhommodeButtonGroup);
            app.ScalarButton_B1.Text = 'Scalar';
            app.ScalarButton_B1.Value = true;
            app.MapButton_B1 = uiradiobutton(app.B1inhommodeButtonGroup);
            app.MapButton_B1.Text = 'Map';
            app.ImportB1map = uibutton(app.rB1Tab, 'push');
            app.ImportB1map.ButtonPushedFcn = createCallbackFcn(app, @ImportB1mapButtonPushed, true);
            app.ImportB1map.Text = 'Import';
            app.shapeDropDown = uidropdown(app.RightPanel);
            app.shapeDropDown.Items = {'Circle', 'Square', 'Triangle'};
            app.shapeDropDown.Value = 'Circle';
            app.expparaLabel_3 = uilabel(app.RightPanel);
            app.expparaLabel_3.Text = 'Phantom number';
            app.GroupsNumber = uieditfield(app.RightPanel, 'numeric');
            app.GroupsNumber.Limits = [1 16];
            app.GroupsNumber.ValueChangedFcn = createCallbackFcn(app, @GroupsNumberValueChanged, true);
            app.GroupsNumber.Value = 9;
            app.FreqoffsppmLabel = uilabel(app.RightPanel);
            app.FreqoffsppmLabel.Text = 'Freq. offs. (ppm)';
            app.FreqoffsppmSlider = uislider(app.RightPanel);
            app.FreqoffsppmSlider.Limits = [-10 10];
            app.FreqoffsppmSlider.ValueChangedFcn = createCallbackFcn(app, @FreqoffsppmSliderValueChanged, true);
            app.ShapeLabel = uilabel(app.RightPanel);
            app.ShapeLabel.Text = 'Shape';
            app.SizeSliderLabel = uilabel(app.RightPanel);
            app.SizeSliderLabel.Text = 'Size';
            app.SizeSlider = uislider(app.RightPanel);
            app.SizeSlider.Limits = [0.2 1];
            app.SizeSlider.Value = 0.75;
            app.FreqOffs_precise = uieditfield(app.RightPanel, 'numeric');
            app.FreqOffs_precise.ValueDisplayFormat = '%.4f';
            app.FreqOffs_precise.ValueChangedFcn = createCallbackFcn(app, @FreqOffs_preciseValueChanged, true);
            app.GaussnoiseSwitchLabel = uilabel(app.RightPanel);
            app.GaussnoiseSwitchLabel.Text = 'Gaussian noise';
            app.sigmaLabel = uilabel(app.RightPanel);
            app.sigmaLabel.Enable = 'off';
            app.sigmaLabel.Interpreter = 'latex';
            app.sigmaLabel.Text = '\sigma';
            app.sigmaEditField = uieditfield(app.RightPanel, 'numeric');
            app.sigmaEditField.Limits = [0 Inf];
            app.sigmaEditField.Enable = 'off';
            app.sigmaEditField.Tooltip = {'Standard deviation of Gaussian noise. The noise is calculated using "sigma*randn(1)" and directly added to simulated signal'};
            app.sigmaEditField.Value = 0.0025;
            app.GaussNoiseOnCheckBox = uicheckbox(app.RightPanel);
            app.GaussNoiseOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussNoiseOnCheckBoxValueChanged, true);
            app.GaussNoiseOnCheckBox.Text = '';
            app.ExpsettingButton = uibutton(app.RightPanel, 'push');
            app.ExpsettingButton.ButtonPushedFcn = createCallbackFcn(app, @ExpsettingButtonPushed, true);
            app.ExpsettingButton.Text = 'Exp. setting';
            app.ClearsettingButton = uibutton(app.RightPanel, 'push');
            app.ClearsettingButton.ButtonPushedFcn = createCallbackFcn(app, @ClearsettingButtonPushed, true);
            app.ClearsettingButton.Text = 'Clear setting';
            app.ExperimentLabel = uilabel(app.RightPanel);
            app.ExperimentLabel.Text = 'Experiment';
            app.DisplayLabel = uilabel(app.RightPanel);
            app.DisplayLabel.Text = 'Display';
            app.LoadsettingButton = uibutton(app.RightPanel, 'push');
            app.LoadsettingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingButtonPushed, true);
            app.LoadsettingButton.Text = 'Load setting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CESTsimu_v071

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end